classdef QuoTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KeepButton                matlab.ui.control.Button
        SaveFile                  matlab.ui.control.EditField
        Query_EditField           matlab.ui.control.TextArea
        K_events_EditField        matlab.ui.control.NumericEditField
        WinSize_EditField         matlab.ui.control.NumericEditField
        ValleyEditField           matlab.ui.control.NumericEditField
        ValleyEditFieldLabel      matlab.ui.control.Label
        PeakEditField             matlab.ui.control.NumericEditField
        PeakEditFieldLabel        matlab.ui.control.Label
        FlatEditField             matlab.ui.control.NumericEditField
        FlatEditFieldLabel        matlab.ui.control.Label
        ComplexityEditField       matlab.ui.control.NumericEditField
        ComplexityEditFieldLabel  matlab.ui.control.Label
        SymmetryEditField         matlab.ui.control.NumericEditField
        SymmetryEditFieldLabel    matlab.ui.control.Label
        DownEditField             matlab.ui.control.NumericEditField
        DownEditFieldLabel        matlab.ui.control.Label
        UpEditField               matlab.ui.control.NumericEditField
        UpEditFieldLabel          matlab.ui.control.Label
        NoiseEditField            matlab.ui.control.NumericEditField
        NoiseEditFieldLabel       matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        SignalSlider              matlab.ui.control.Slider
        UpdateButton              matlab.ui.control.Button
        UIAxes_OrderedOutput      matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XALL %container with multichannel data, such as physionet
        SIGNAL % container with the signal
        MOVING_SIGNAL %contains the signal separated with the total overlap windows

        KEY_VECTOR1 % container with the representation of the signal over the set of keywords and window size 1
        KEY_VECTOR2 % container with the representation of the signal over the set of keywords and window size 1/4
        KEY_VECTOR3 %contianer with the representation of the signal over the set of keywords and window size 1/16

        COLORS % Colors used for windows

        PATCH % object to use the sliding window along the signal
        SCORE % score array

        ITER = 0; % Iterator that defines if it is on the beginning or end


        OrderedScore % Score [0-1] of the found subsequences
        OrderedPosition % Position of the corresponding subsequence stored in OrderedScore
    end
    
    methods (Access = private)

        %------------------------------------------------------------------
        % Loading and Initialization Functions
        %------------------------------------------------------------------
        
        function [y, X] = loadUCR(app, name)
        %This function loads the UCR dataset by concatenating the signals
        %horizontally, creating a single signal

            %Disable Channel selection
            app.Channel_EditField.Enable = "off";
            %load dataset
            ucr_name_lst = split(name, '_');
            ucr_name = ucr_name_lst(2);
            data = importdata("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\UCR_data\Univariate_arff\"+ucr_name + "\"+ucr_name+"_TEST.txt", "\n");
            data = vertcat(data{:});
            data = str2num(data);
            
            n = fix(size(data, 1)/4);
            y = data(1:n, 1);
            X1 = data(1:n, 2:end);
            X = zeros(1, size(X1,1)*size(X1,2));
            for i = 1:length(X1(:,1))
                %simple smoothing function
                s_i = movmean(X1(i, :), 10);
                %add by column to create a single signal, normalized
                %between [0, 1]
                X((i-1)*size(X1,2)+1:i*size(X1,2)) = app.norm_1_sig(s_i);
            end
        end

        function [y, X] = loadPhysio(app, name)
            %Enable Channel selection
            app.Channel_EditField.Enable = "on";
            channel = app.Channel_EditField.Value;
            %load physionet data
            s = load("PhysionetData.mat");
            app.XALL = s.Signals;
            y = 0;
            X = app.norm_1_sig(app.XALL{channel});
        end

        function [y, X] = loadBird(app, name)
            %Enable Channel selection
%             app.Channel_EditField.Enable = "on";
%             channel = app.Channel_EditField.Value;
            %load physionet data
            s = load("UCR_data\bird_songs\bird_song_mfcc3.mat").mybird3;
            app.XALL = s(1:4000);
            y = 0;
            X = app.norm_1_sig(app.XALL);
        end

        function [y, X] = loadmultiCar(app, name)
            %Enable Channel selection-think about that later
%             app.Channel_EditField.Enable = "on";
%             channel = app.Channel_EditField.Value;
            %load multi_signal car
            path = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110175712-16km-D1-NORMAL1-SECONDARY";
            acc = load(path+"\RAW_ACCELEROMETERS.txt");
            gps = load(path+"\RAW_GPS.txt");
            duration = 0.4*length(acc(:,1));
            
%             change_lane_events = load(path+"\annotations.txt");

            
%             x_events = fix(10*(change_lane_events(:, 1) - acc(1, 1)));
           
            y_events = zeros(1, length(acc(:,5)));
%             y_events(x_events) = 1;

            x_acc = linspace(1, length(acc(:, 5)), length(acc(:,5)));
            x_gps = linspace(1, length(acc(:, 5)), length(gps(:,2)));
            gps = interp1(x_gps, gps(:,2), x_acc);
            z_axis = transpose(acc(:, 8));
            y_axis = transpose(acc(:, 7));
            yaw = transpose(acc(:, 11));
%             z_axis = sin(2*pi*x_acc);
%             y_axis = tan(2*pi*x_acc);
%             gps = cos(2*pi*x_acc);

            app.XALL = [
                app.norm_1_sig(movmean(z_axis(1:duration), 5)); %Z-axis - longitudinal axis 
                app.norm_1_sig(movmean(y_axis(1:duration), 5)); %Y-axis - lateral axis
                app.norm_1_sig(yaw(1:duration));    %heading angle
                app.norm_1_sig(gps(1:duration));    %Speed - speed from gps, interpolated to have the same samples as acc
                y_events(1:duration)
                ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_multi_car2(app, name)
            s = load("UCR_data\driving_dataset\driving2_ours\data_test\Scenario1_2022-01-22_11-38-19\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-38-19.txt");
%             s2 = load("UCR_data\driving_dataset\driving2_ours\data_test\Scenario1_2022-01-22_11-38-19\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-38-19.txt");
            
            acc_x = transpose(movmean(s(:, 2), 100));
            acc_y = transpose(movmean(s(:, 3), 100));
            acc_z = transpose(movmean(s(:, 4), 100));

%             gyr_x = transpose(movmean(s2(:, 2),10));
%             gyr_y = transpose(movmean(s2(:, 3), 10));
%             gyr_z = transpose(movmean(s2(1:size(s2,1), 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
                app.norm_1_sig(acc_z);
                
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car3(app, name)
            %load dataset with roundabouts
            s = load("UCR_data\driving_dataset\driving2_ours\data_test\Roundabouts_2022-01-22_11-45-42\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-45-42.txt");
            gyr = load("UCR_data\driving_dataset\driving2_ours\data_test\Roundabouts_2022-01-22_11-45-42\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-45-42.txt");
            
%             acc_x = transpose(movmean(s(1:size(gyr,1), 2),10));
            acc_y = transpose(movmean(s(1:size(gyr,1), 3), 10));
%             acc_z = transpose(movmean(s(1:size(gyr,1), 4), 10));
            gyr_z = transpose(movmean(gyr(:, 4), 10));
            gyr_x = transpose(movmean(gyr(:, 2), 10));
            gyr_y = transpose(movmean(gyr(:, 3), 10));
            
            app.XALL = [
                app.norm_1_sig(gyr_z);
                app.norm_1_sig(acc_y);
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car4(app, name)
            %load dataset with californian and normal stops

            s = load("UCR_data\driving_dataset\driving2_ours\data_test\StopVSCAStop_2022-01-22_11-47-48\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-47-48.txt");
%             gyr = load("UCR_data\driving_dataset\driving2_ours\data_test\2022-01-22_11-47-48\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-47-48.txt");
            
            acc_x = transpose(movmean(s(:, 2),50));
            acc_y = transpose(movmean(s(:, 3), 10));
%             acc_z = transpose(movmean(s(:, 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
%                 app.norm_1_sig(acc_z)
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car5(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\UCR_data\driving_dataset\driving2_ours\data_test\SpeedBumps_2022-01-22_11-52-59\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-52-59.txt");
%             s = load("UCR_data\driving_dataset\driving2_ours\data_test\2022-01-22_11-52-59\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-52-59.txt");
            
            acc_x = transpose(movmean(s(:, 2),50));
            acc_y = transpose(movmean(s(:, 3), 10));
            acc_z = transpose(movmean(s(:, 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
                app.norm_1_sig(acc_z)
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car_TEST_Normal(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:71844, 2),10));
            acc_y = transpose(movmean(s(1:71844, 3), 10));
            acc_z = transpose(movmean(s(1:71844, 4), 10));

            gyr_z = transpose(movmean(s2(1:71844, 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;          
        end

        function [y, X] = load_multi_car_TEST_NandA(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:89587, 2),10));
            acc_y = transpose(movmean(s(1:89587, 3), 10));
            acc_z = transpose(movmean(s(1:89587, 4), 10));

            gyr_z = transpose(movmean(s2(1:89587, 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;          
        end

        function [y, X] = load_multi_car6(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:size(s2,1), 2),10));
            acc_y = transpose(movmean(s(1:size(s2,1), 3), 10));
            acc_z = transpose(movmean(s(1:size(s2,1), 4), 10));

            gyr_z = transpose(movmean(s2(1:size(s2,1), 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_multi_car7(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\free_road\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-56-59.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\free_road\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-56-59.txt");
            

            acc_x = transpose(movmean(s(1:size(s,1), 2),10));
            acc_y = transpose(movmean(s(1:size(s,1), 3), 10));
            acc_z = transpose(movmean(s(1:size(s,1), 4), 10));

            gyr_x = transpose(movmean(s2(1:size(s,1), 2), 10));
            gyr_y = transpose(movmean(s2(1:size(s,1), 3), 10));
            gyr_z = transpose(movmean(s2(1:size(s,1), 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_x;
                gyr_y;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_car_UI(app, name, ind_, road_, events_)
            if(ind_ == 6)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D6\20151217162714-26km-D6-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D6\20151221120051-26km-D6-AGGRESSIVE-MOTORWAY";
                end
            elseif(ind_ == 5)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151209151242-25km-D5-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151209153137-25km-D5-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151211164124-17km-D5-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151211162829-16km-D5-NORMAL1-SECONDARY";
                end

            elseif(ind_== 4)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151204152848-25km-D4-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151204154908-25km-D4-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151203173103-17km-D4-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151203171800-16km-D4-NORMAL1-SECONDARY";
                end

            elseif(ind_== 3)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126110502-26km-D3-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126134736-26km-D3-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126125458-16km-D3-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126124208-16km-D3-NORMAL1-SECONDARY";
                end

            elseif(ind_== 2)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120131714-26km-D2-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120133502-26km-D2-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120162105-17km-D2-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120160904-16km-D2-NORMAL1-SECONDARY";
                end

            elseif(ind_== 1)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151111123124-25km-D1-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151111125233-24km-D1-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110180824-16km-D1-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110175712-16km-D1-NORMAL1-SECONDARY";
                end
            end
            s_norm = load(path_normal+"\RAW_ACCELEROMETERS.txt");
            s_aggressive = load(path_aggressive+"\RAW_ACCELEROMETERS.txt");
            
            if(events_==1)
                acc_x = transpose(s_norm(:, 6));
                acc_y = transpose(s_norm(:, 7));
                acc_z = transpose(s_norm(:, 8));
            elseif(events_==0)
                acc_x = [transpose(s_norm(:, 6)), mean(s_norm(:, 6))*ones(1, 100), transpose(s_aggressive(:, 6))];
                acc_y = [transpose(s_norm(:, 7)), mean(s_norm(:, 7))*ones(1, 100), transpose(s_aggressive(:, 7))];
                acc_z = [transpose(s_norm(:, 8)), mean(s_norm(:, 8))*ones(1, 100), transpose(s_aggressive(:, 8))];
            end

            app.XALL = [
                acc_x;
                acc_y;
                acc_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_car_AUI(app, driver)
            data = readtable("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\hcilab_driving_dataset\dataset_web\participant_"+driver+".csv");
            acc_x = transpose(movmean(data.AccelX, 250));
            acc_z = -transpose(movmean(data.AccelZ, 250));
            speed = transpose(data.Speed_GPS);

            app.XALL = [
                acc_x(1:2:end);
                acc_z(1:2:end);
                speed(1:2:end)
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_segment(app)
            %Enable Channel selection
            app.Channel_EditField.Enable = "on";
            channel = app.Channel_EditField.Value;
            
            myFiles = dir(fullfile('C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\datasets_seg\datasets_seg\','*.txt'));
            files = cell(1, length(myFiles));
            for k = 1:length(myFiles)
                baseFileName = myFiles(k).name;
                fullFileName = fullfile('C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\datasets_seg\datasets_seg', baseFileName);
                fprintf(1, 'Now reading %s\n', fullFileName);
                files{k} = transpose(load(fullFileName));
            end
            app.XALL = files;
            X = app.norm_1_sig(app.XALL{channel});
            y = 0;
        end

        function [y, X] = load_multisignal_test1(app)
            s1 = zeros(1, 1000);
            s2 = zeros(1, 1000);
            
            s1(250:500) = linspace(1, 250, 251);
            s1(750:900) = linspace(1, 150, 151);
            s2(250:500) = sin(2*pi*linspace(1, 250, 251));

            app.XALL = [app.norm_1_sig(s1);
                app.norm_1_sig(s2)];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_single_signal(app)
            s1 = 0.1*rand(1, 1000) - 0.1;
            
            s1(250:500) = linspace(0, 1, 251);
            s1(750:900) = linspace(0, 1, 151);
            
            app.XALL = s1;

            y = 0;
            X = app.XALL;
        end

        function plot_slider_init(app, single)
            %----------------------------------------------
            %   Slider Definition
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;
            %slider limits
            app.SignalSlider.Limits = [window_size, nbr_windows-window_size];
            
            %main plot
            hold(app.UIAxes, 'off');
            if(single)
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 1, 'Color', 'b')
            else
                for i = 1:size(app.SIGNAL, 1)
                    plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1)
                    hold(app.UIAxes, 'on')
                end
            end

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL, 1)])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, size(app.SIGNAL, 2)+rectWidth])

            %update plot
            if(single)
                app.updateSubSectionPlot(fix(window_size/2));
            else
                app.updateSubSectionPlot_multi(fix(window_size/2));
            end
        end

        function sub_load_signals(app)
            %load word_feature vectors
            [app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3] = word_feature_extraction(app.XALL, app.WinSize_EditField.Value);
            %plot
            app.plot_slider_init(1);
        end


        %------------------------------------------------------------------
        % Multi Dimensional methods
        %------------------------------------------------------------------

        function sub_load_signals_multi(app)
            %word feature vectors
            [app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3] = word_feature_extraction(app.XALL, app.WinSize_EditField.Value);
            %plot
            app.plot_slider_init(0)
        end

        function updateSubSectionPlot_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            %plot first signal
            section = app.SIGNAL(1, a:b);
            plot(app.UIAxes2, section)
        end


        %------------------------------------------------------------------
        % Normalization methods
        %------------------------------------------------------------------

        function norm_sig = norm_1_sig(~, s)
            if(max(s)==min(s))
                norm_sig = zeros(1, length(s));
            else
                norm_sig = (s - min(s))/(max(s)-min(s));
            end
        end

        %------------------------------------------------------------------
        % Sliding Update methods
        %------------------------------------------------------------------

        function recreatePatchwindow(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, length(app.SIGNAL)+win_size])
            
        end
        
        function updateSubSectionPlot(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            if(size(app.SIGNAL,1)>1)
                section = app.SIGNAL(1, a:b);
            else
                section = app.SIGNAL(a:b);
            end
            plot(app.UIAxes2, section)
        end


        function recreatePatchwindow_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL,1)])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, size(app.SIGNAL, 2)+win_size])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function launchApp(app, X, win_size)
            app.XALL = X;
            app.WinSize_EditField.Value = win_size;
            %In this case, there are no classes...
            colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
            app.COLORS = colors;  
            %we have signal now!
            app.ITER = 1;
            app.SIGNAL = X;
            %load keyword representation of the signal extracted in
            %multiple dimensions
            if(size(X, 1) > 1)
                app.sub_load_signals_multi();
            else
                app.sub_load_signals();
            end
        end

        % Button pushed function: UpdateButton
        function LoadSignal(app, event)
            %update signal word feature vectors based on new size
            if(size(app.XALL, 1) > 1)
                app.sub_load_signals_multi();
            else
                app.sub_load_signals();
            end
        end

        % Value changing function: SignalSlider
        function SignalSliderValueChanging(app, event)
            %Get value from slider
            changingValue = fix(event.Value);
            
            if(changingValue+1>(length(app.SIGNAL)-app.WinSize_EditField.Value/2))
                changingValue = fix(length(app.SIGNAL)-app.WinSize_EditField.Value/2)-1;
            end

            %update plot
            app.updateSubSectionPlot(changingValue);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %Parse text----------------------------------------------
            %   Idea 2 - Check if keywords with space or brackets
            %   indicating window -> Now using regex!!!
            %   Idea 3 (Now in version 4 of the app) - include followed
            %   by/next to/after/before (for now...only using 1 time and
            %   not multiple instances of that operator)
            %--------------------------------------------------------
            
            query = char(app.Query_EditField.Value);
            win_size = app.WinSize_EditField.Value;
            
            % 1 - check if multi_part search or single part search:
            scores = query_search(app.XALL, app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3, query, win_size);
            
            %PLOT---------------------------------------------------------
            %from the retrieved scores, display the segments found
            %put scores in same dimension as SIGNAL
            new_scores = zeros(1, length(scores)+win_size);
            new_scores(fix(win_size/2):end-fix(win_size/2)-1) = scores;
            x_scores = linspace(1, length(new_scores), length(new_scores));
            half_win = fix(win_size/2);

            app.SCORE = new_scores;
            
            %3 - plot score function on axes
            plot(app.UIAxes_2, new_scores)

            %4 Order by k-most representative windows
            %5 highlight most important windows
            %6 plot eveything in the corresponding plots
            k = app.K_events_EditField.Value;
            arg_k = zeros(1, k);
            hold(app.UIAxes,'off');
            if(size(app.XALL,1)>1)
                for i = 1:size(app.SIGNAL, 1)
                    plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1, 'Color', '#808080')
                    hold(app.UIAxes, 'on');
                end
            else
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 0.8, 'Color', '#808080')
                hold(app.UIAxes, 'on');
            end

            cmap = colormap(jet(100));
            all_ks = [];
            all_scores = [];
            for k_i = 1:k
                %find max
                [max_ki, argmax_ki] = max(new_scores);
                %save index to plot around it
                arg_k(k_i) = fix(win_size/2) + argmax_ki;
                
                %remove the score and its surrounding 
                a = argmax_ki-half_win+1;
                b = argmax_ki+half_win;
                all_ks = [all_ks, argmax_ki];
                all_scores = [all_scores, max_ki];
                new_scores(a:b) = 0;
                %highlight on plot
                if(size(app.XALL,1)>1)
                    for i = 1:size(app.SIGNAL, 1)
                        %plot subsequences on signal
                        s_ = app.norm_1_sig(app.SIGNAL(i,:));
                        plot(app.UIAxes, x_scores(a:b), s_(a:b)+(i-1)*1.5, 'Color', cmap(fix(max_ki*85), :), 'LineWidth', 2)
                        %plot ordered on side
                        x_i = linspace(-1+i, i-0.5, length(a:b));
                        plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i)*max_ki, 'LineWidth', 2, 'Color', cmap(fix(max_ki*85), :))
                        hold(app.UIAxes_OrderedOutput, 'on')
                    end
                    
                else
                    s_ = app.norm_1_sig(app.SIGNAL);
                    plot(app.UIAxes, x_scores(a:b), s_(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
                    x_i = linspace(0, 0.5, length(a:b));
                    plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i), 'LineWidth', 2)
                    hold(app.UIAxes_OrderedOutput, 'on')
                end
                %keep searching the next higher window
            end
           
            app.OrderedPosition = all_ks;
            app.OrderedScore = all_scores;

            xlim(app.UIAxes_OrderedOutput, [-0.5, size(app.SIGNAL, 1)])
            hold(app.UIAxes_OrderedOutput, 'off')
            
            if(size(app.XALL, 1)>1)
                app.recreatePatchwindow_multi(app.SignalSlider.Value);
            else
                app.recreatePatchwindow(app.SignalSlider.Value);
            end

        end

        % Callback function
        function Channel_EditFieldValueChanged(app, event)
            value = app.Channel_EditField.Value;
            
            app.SIGNAL = app.norm_1_sig(app.XALL{value});

            %load keyword representation of the signal extracted in
            %multiple dimensions
            app.loadKeywords()
            
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;
            app.SignalSlider.Limits = [fix(window_size/2), nbr_windows-fix(window_size/2)];
            
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])

            %make first update on edit field scores and subplot
            %update edit field scores
            app.updateEditFieldsScores(fix(window_size/2));

            %update plot
            app.updateSubSectionPlot(fix(window_size/2));
        end

        % Value changed function: WinSize_EditField
        function WinSize_EditFieldValueChanged(app, event)
%             if(app.ITER > 0)
%                 window_size = app.WinSize_EditField.Value;
%                 %load keyword representation of the signal extracted in
%                 %multiple dimensions
%                 app.loadKeywords()
%         
%                 %In this case, there are no classes...
%                 colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
%                 app.COLORS = colors;                      
%                 
%                 hold(app.UIAxes, 'off');
%                 plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')
%         
%                 %create rectangle Patch on plot
%                 rectWidth = window_size;
%                 rectX = rectWidth*[0, 1];
%                 ylim(app.UIAxes, [-0.5, 1.5])
%                 rectY = ylim(app.UIAxes);
%                 app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
%                 xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])
%         
%                 %make first update on edit field scores and subplot
%                 %update edit field scores
%                 app.updateEditFieldsScores(fix(window_size/2));
%         
%                 %update plot
%                 app.updateSubSectionPlot(fix(window_size/2));
%             end
        end

        % Value changed function: K_events_EditField
        function K_events_EditFieldValueChanged(app, event)
%             k = app.K_events_EditField.Value;
%             win_size = app.WinSize_EditField.Value;
%             half_win = fix(win_size/2);
% 
%             new_scores = app.SCORE;
%             x_scores = linspace(1, length(app.SIGNAL), length(app.SIGNAL));
% 
%             arg_k = zeros(1, k);
%             hold(app.UIAxes,'off');
%             plot(app.UIAxes, app.SIGNAL, 'LineWidth', 0.8, 'Color', '#808080')
%             hold(app.UIAxes, 'on');
%             for k_i = 1:k
%                 %find max
%                 [max_ki, argmax_ki] = max(new_scores);
%                 %save index to plot around it
%                 arg_k(k_i) = half_win + argmax_ki;
%                 %remove the score and its surrounding 
%                 a = argmax_ki-half_win+1;
%                 b = argmax_ki+half_win;
%                 new_scores(a:b) = 0;
%                 %highlight on plot
%                 plot(app.UIAxes, x_scores(a:b), app.SIGNAL(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
%                 %keep searching the next higher window
%             end
%             
%             app.recreatePatchwindow(app.SignalSlider.Value);
            
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            file_data = [app.OrderedPosition; app.OrderedScore];
            name = app.SaveFile.Value;
            writematrix(file_data, name+".txt");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_OrderedOutput = uiaxes(app.UIFigure);
            app.UIAxes_OrderedOutput.XTick = [];
            app.UIAxes_OrderedOutput.YTick = [];
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignal, true);
            app.UpdateButton.Text = 'Update';
            app.SignalSlider = uislider(app.UIFigure);
            app.SignalSlider.Limits = [1 100];
            app.SignalSlider.MajorTickLabels = {''};
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @SignalSliderValueChanging, true);
            app.SignalSlider.MinorTicks = [];
            app.SignalSlider.Value = 1;
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpEditFieldLabel = uilabel(app.UIFigure);
            app.UpEditFieldLabel.Text = 'Up';
            app.UpEditField = uieditfield(app.UIFigure, 'numeric');
            app.DownEditFieldLabel = uilabel(app.UIFigure);
            app.DownEditFieldLabel.Text = 'Down';
            app.DownEditField = uieditfield(app.UIFigure, 'numeric');
            app.SymmetryEditFieldLabel = uilabel(app.UIFigure);
            app.SymmetryEditFieldLabel.Text = 'Symmetry';
            app.SymmetryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComplexityEditFieldLabel = uilabel(app.UIFigure);
            app.ComplexityEditFieldLabel.Text = 'Complexity';
            app.ComplexityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlatEditFieldLabel = uilabel(app.UIFigure);
            app.FlatEditFieldLabel.Text = 'Flat';
            app.FlatEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakEditFieldLabel = uilabel(app.UIFigure);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValleyEditFieldLabel = uilabel(app.UIFigure);
            app.ValleyEditFieldLabel.Text = 'Valley';
            app.ValleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.WinSize_EditField = uieditfield(app.UIFigure, 'numeric');
            app.WinSize_EditField.ValueChangedFcn = createCallbackFcn(app, @WinSize_EditFieldValueChanged, true);
            app.WinSize_EditField.Value = 50;
            app.K_events_EditField = uieditfield(app.UIFigure, 'numeric');
            app.K_events_EditField.ValueChangedFcn = createCallbackFcn(app, @K_events_EditFieldValueChanged, true);
            app.K_events_EditField.Value = 10;
            app.Query_EditField = uitextarea(app.UIFigure);
            app.Query_EditField.Placeholder = 'Enter query...';
            app.SaveFile = uieditfield(app.UIFigure, 'text');
            app.KeepButton = uibutton(app.UIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuoTS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)launchApp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end