classdef NL_UCR_DATA_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Class2matchEditField       matlab.ui.control.EditField
        Class2matchEditFieldLabel  matlab.ui.control.Label
        SignalSlider               matlab.ui.control.Slider
        SignalSliderLabel          matlab.ui.control.Label
        KeywordInfoforSpecificWindowPanel  matlab.ui.container.Panel
        ValleyEditField_4          matlab.ui.control.NumericEditField
        ValleyEditField_3          matlab.ui.control.NumericEditField
        ValleyEditField_2          matlab.ui.control.NumericEditField
        ValleyEditField            matlab.ui.control.NumericEditField
        ValleyEditFieldLabel       matlab.ui.control.Label
        PeakEditField              matlab.ui.control.NumericEditField
        PeakEditFieldLabel         matlab.ui.control.Label
        PeakEditField_4            matlab.ui.control.NumericEditField
        PeakEditField_3            matlab.ui.control.NumericEditField
        PeakEditField_2            matlab.ui.control.NumericEditField
        FlatEditField_4            matlab.ui.control.NumericEditField
        ComplexityEditField_4      matlab.ui.control.NumericEditField
        SymmetryEditField_4        matlab.ui.control.NumericEditField
        DownEditField_4            matlab.ui.control.NumericEditField
        UpEditField_4              matlab.ui.control.NumericEditField
        NoiseEditField_4           matlab.ui.control.NumericEditField
        FlatEditField_3            matlab.ui.control.NumericEditField
        ComplexityEditField_3      matlab.ui.control.NumericEditField
        SymmetryEditField_3        matlab.ui.control.NumericEditField
        DownEditField_3            matlab.ui.control.NumericEditField
        UpEditField_3              matlab.ui.control.NumericEditField
        NoiseEditField_3           matlab.ui.control.NumericEditField
        FlatEditField_2            matlab.ui.control.NumericEditField
        ComplexityEditField_2      matlab.ui.control.NumericEditField
        SymmetryEditField_2        matlab.ui.control.NumericEditField
        DownEditField_2            matlab.ui.control.NumericEditField
        UpEditField_2              matlab.ui.control.NumericEditField
        NoiseEditField_2           matlab.ui.control.NumericEditField
        FlatEditField              matlab.ui.control.NumericEditField
        FlatEditFieldLabel         matlab.ui.control.Label
        ComplexityEditField        matlab.ui.control.NumericEditField
        ComplexityEditFieldLabel   matlab.ui.control.Label
        SymmetryEditField          matlab.ui.control.NumericEditField
        SymmetryEditFieldLabel     matlab.ui.control.Label
        DownEditField              matlab.ui.control.NumericEditField
        DownEditFieldLabel         matlab.ui.control.Label
        UpEditField                matlab.ui.control.NumericEditField
        UpEditFieldLabel           matlab.ui.control.Label
        NoiseEditField             matlab.ui.control.NumericEditField
        NoiseEditFieldLabel        matlab.ui.control.Label
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        OverallResultsPanel        matlab.ui.container.Panel
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        FNEditField                matlab.ui.control.NumericEditField
        FNEditFieldLabel           matlab.ui.control.Label
        TNEditField                matlab.ui.control.NumericEditField
        TNEditFieldLabel           matlab.ui.control.Label
        FPEditField                matlab.ui.control.NumericEditField
        FPEditFieldLabel           matlab.ui.control.Label
        TPEditField                matlab.ui.control.NumericEditField
        TPEditFieldLabel           matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        Query_EditField            matlab.ui.control.EditField
        UCR_load_EditField         matlab.ui.control.EditField
        LoadButton                 matlab.ui.control.Button
        UIAxes2_Representation     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %initial variables
        XData
        CLASSES
        COLORS
        NBR_DIMENSIONS
        SIZE_DIMENSION
        
        %Keyword Data
        KEY_VECTOR1
        KEY_VECTOR2
        KEY_VECTOR3

        
        Y

        %Score Matrix
        SCORE
        SCORES

        Patch % Description
    end
    
    methods (Access = private)
        
         function [y, X] = loadUCR(app, name)
            data = importdata("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\UCR_data\Univariate_arff\"+name + "\"+name+"_TEST.txt", "\n");
            data = vertcat(data{:});
            data = str2num(data);
%             n = 1000;
            y = data(1:end, 1);
            X1 = data(1:end, 2:end);
            X = [];
            for i = 1:length(X1(:,1))
                s_i = X1(i, :);
%                 s_i = s_i;
                %Add by row this time, so that I can treat 
                % each signal individually
                X = [X; s_i]; 
            end
         end

         function [y, X] = loadUCR_multi(app, name)
             [y1, X1] = app.loadUCR("UWaveGestureLibraryX");
             [y2, X2] = app.loadUCR("UWaveGestureLibraryY");
             [y3, X3] = app.loadUCR("UWaveGestureLibraryZ");

             y = [y1; y2; y3];
             X = [X1; X2; X3];

             app.SIZE_DIMENSION = size(X1, 1);
             app.NBR_DIMENSIONS = fix(size(X, 1)/size(X2, 1));
         end
        
        function a = load_keywords(app, X)
            app.NoiseData1 = [];
            app.NoiseData2 = [];
            app.NoiseData3 = [];
            app.NoiseData4 = [];

            app.SlopeData1 = [];
            app.SlopeData2 = [];
            app.SlopeData3 = [];
            app.SlopeData4 = [];

            app.PosSlopeData1 = [];
            app.PosSlopeData2 = [];
            app.PosSlopeData3 = [];
            app.PosSlopeData4 = [];

            app.NegSlopeData1 = [];
            app.NegSlopeData2 = [];
            app.NegSlopeData3 = [];
            app.NegSlopeData4 = [];

            app.ComplexityData1 = [];
            app.ComplexityData2 = [];
            app.ComplexityData3 = [];
            app.ComplexityData4 = [];

            app.SymmetryData1 = [];
            app.SymmetryData2 = [];
            app.SymmetryData3 = [];
            app.SymmetryData4 = [];

            app.FlatData1 = [];
            app.FlatData2 = [];
            app.FlatData3 = [];
            app.FlatData4 = [];

            app.PeakData1 = [];
            app.PeakData2 = [];
            app.PeakData3 = [];
            app.PeakData4 = [];

            app.ValleyData1 = [];
            app.ValleyData2 = [];
            app.ValleyData3 = [];
            app.ValleyData4 = [];
            
            for i = 1:size(X,1)
                [noise_i, up_i, down_i, flat_i, symmetry_i, complex_i, peak_i, valley_i] = app.moving_feature_extraction(X(i, :), fix(size(X, 2)/8));
                [noise_i2, up_i2, down_i2, flat_i2, symmetry_i2, complex_i2, peak_i2, valley_i2] = app.moving_feature_extraction(X(i, :), fix(size(X, 2)/16));
                
                noise_i3 = noise_i;
                up_i3 = up_i;
                down_i3 = down_i;
                flat_i3 = flat_i;
                symmetry_i3 = symmetry_i;
                complex_i3 = complex_i;
                peak_i3 = peak_i;
                valley_i3 = valley_i;
             % [noise_i3, up_i3, down_i3, flat_i3, symmetry_i3, complex_i3, peak_i3, valley_i3] = app.moving_feature_extraction(X(i, :), fix(size(X, 2)/8));

                %Noise
                app.NoiseData1 = [app.NoiseData1; app.cum_wbr(X(i, :))];
                app.NoiseData2 = [app.NoiseData2; noise_i]; 
                app.NoiseData3 = [app.NoiseData3; noise_i2];
                app.NoiseData4 = [app.NoiseData4; noise_i3];
    
                %Slope
%                 [pos_sl, neg_sl] = app.piecewise_area_slope_estimation(X(i,:), 10);%level1
                
                app.PosSlopeData1 = [app.PosSlopeData1; sum(up_i)/(sum(up_i+down_i))];
                app.PosSlopeData2 = [app.PosSlopeData2; up_i./(sum(up_i+down_i))];
                app.PosSlopeData3 = [app.PosSlopeData3; up_i2./(sum(up_i2+down_i2))];
                app.PosSlopeData4 = [app.PosSlopeData4; up_i3./(sum(up_i3+down_i3))];
                
                app.NegSlopeData1 = [app.NegSlopeData1; sum(down_i)./(sum(up_i+down_i))];
                app.NegSlopeData2 = [app.NegSlopeData2; down_i./(sum(up_i+down_i))];
                app.NegSlopeData3 = [app.NegSlopeData3; down_i2./(sum(up_i2+down_i2))];
                app.NegSlopeData4 = [app.NegSlopeData4; down_i3./(sum(up_i3+down_i3))];

                %Flat
                app.FlatData1 = [app.FlatData1; sum(flat_i)];
                app.FlatData2 = [app.FlatData2; flat_i];
                app.FlatData3 = [app.FlatData3; flat_i2];
                app.FlatData4 = [app.FlatData4; flat_i3];
    
                %Complexity
                app.ComplexityData1 = [app.ComplexityData1; app.complexity_estimation(X(i,:))];
                app.ComplexityData2 = [app.ComplexityData2; complex_i];
                app.ComplexityData3 = [app.ComplexityData3; complex_i2];
                app.ComplexityData4 = [app.ComplexityData4; complex_i3];
                
                %Symmetry
                app.SymmetryData1 = [app.SymmetryData1; app.symmetry_estimation(X(i,:))];
                app.SymmetryData2 = [app.SymmetryData2; symmetry_i];
                app.SymmetryData3  = [app.SymmetryData3; symmetry_i2];
                app.SymmetryData4  = [app.SymmetryData4; symmetry_i3];
                
                %Peak and Valley
                [peaks1, vals1] = app.estimate_peaks_vals(X(i,:));
                app.PeakData1 = [app.PeakData1; peaks1];
                app.PeakData2 = [app.PeakData2; peak_i];
                app.PeakData3 = [app.PeakData3; peak_i2];
                app.PeakData4 = [app.PeakData4; peak_i3];

                app.ValleyData1 = [app.ValleyData1; vals1];
                app.ValleyData2 = [app.ValleyData2; valley_i];
                app.ValleyData3 = [app.ValleyData3; valley_i2];
                app.ValleyData4 = [app.ValleyData4; valley_i3];
            end

            %normalize_values
            app.NoiseData1 = app.norm_1_sig(app.NoiseData1);
            app.NoiseData2 = app.norm_1_2d(app.NoiseData2);
            app.NoiseData3 = app.norm_1_2d(app.NoiseData3);
            app.NoiseData4 = app.norm_1_2d(app.NoiseData4);
            app.NoiseDataDict = containers.Map({'1','2','3','4'},{app.NoiseData1, app.NoiseData2, app.NoiseData3, app.NoiseData4});

%             app.PosSlopeData1 = app.norm_1_sig_with_ref(app.PosSlopeData1, min(ref_slope_data1), max(ref_slope_data1));
            app.PosSlopeData2 = app.norm_1_2d(app.PosSlopeData2);
            app.PosSlopeData3 = app.norm_1_2d(app.PosSlopeData3);
            app.PosSlopeData4 = app.norm_1_2d(app.PosSlopeData4);
            app.PosSlopeDataDict = containers.Map({'1','2','3','4'},{app.PosSlopeData1, app.PosSlopeData2, app.PosSlopeData3, app.PosSlopeData4});

%             app.NegSlopeData1 = app.norm_1_sig_with_ref(app.NegSlopeData1, min(ref_slope_data1), max(ref_slope_data1));
            app.NegSlopeData2 = app.norm_1_2d(app.NegSlopeData2);
            app.NegSlopeData3 = app.norm_1_2d(app.NegSlopeData3);
            app.NegSlopeData4 = app.norm_1_2d(app.NegSlopeData4);
            app.NegSlopeDataDict = containers.Map({'1','2','3','4'},{app.NegSlopeData1, app.NegSlopeData2, app.NegSlopeData3, app.NegSlopeData4});

            app.FlatData1 = 1 - app.norm_1_sig(app.FlatData1);
            app.FlatData2 = 1 - app.norm_1_2d(app.FlatData2);
            app.FlatData3 = 1 - app.norm_1_2d(app.FlatData3);
            app.FlatData4 = 1 - app.norm_1_2d(app.FlatData4);
            app.FlatDataDict = containers.Map({'1','2','3','4'},{app.FlatData1, app.FlatData2, app.FlatData3, app.FlatData4});

            app.ComplexityData1 = app.norm_1_sig(app.ComplexityData1);
            app.ComplexityData2 = app.norm_1_2d(app.ComplexityData2);
            app.ComplexityData3 = app.norm_1_2d(app.ComplexityData3);
            app.ComplexityData4 = app.norm_1_2d(app.ComplexityData4);
            app.ComplexityDataDict = containers.Map({'1','2','3','4'},{app.ComplexityData1, app.ComplexityData2, app.ComplexityData3, app.ComplexityData4});

            app.SymmetryData1 = 1 - app.norm_1_sig(app.SymmetryData1);
            app.SymmetryData2 = 1 - app.norm_1_2d(app.SymmetryData2);
            app.SymmetryData3 = 1 - app.norm_1_2d(app.SymmetryData3);
            app.SymmetryData4 = 1 - app.norm_1_2d(app.SymmetryData4);
            app.SymmetryDataDict = containers.Map({'1','2','3','4'},{app.SymmetryData1, app.SymmetryData2, app.SymmetryData3, app.SymmetryData4});

%             app.PeakData1 = 1 - app.norm_1_sig(app.PeakData1);
%             app.PeakData2 = 1 - app.norm_1_2d(app.PeakData2);
%             app.PeakData3 = 1 - app.norm_1_2d(app.PeakData3);
%             app.PeakData4 = 1 - app.norm_1_2d(app.PeakData4);
            app.PeakDataDict = containers.Map({'1','2','3','4'},{app.PeakData1, app.PeakData2, app.PeakData3, app.PeakData4});

%             app.ValleyData1 = 1 - app.norm_1_sig(app.ValleyData1);
%             app.ValleyData2 = 1 - app.norm_1_2d(app.ValleyData2);
%             app.ValleyData3 = 1 - app.norm_1_2d(app.ValleyData3);
%             app.ValleyData4 = 1 - app.norm_1_2d(app.ValleyData4);
            app.ValleyDataDict = containers.Map({'1','2','3','4'},{app.ValleyData1, app.ValleyData2, app.ValleyData3, app.ValleyData4});
            
            
            %Edit values in corresponding fields
            app.NoiseEditField.Value = app.NoiseData1(1);
            app.UpEditField.Value = app.PosSlopeData1(1);
            app.DownEditField.Value = app.NegSlopeData1(1);
            app.FlatEditField.Value = app.FlatData1(1);
            app.ComplexityEditField.Value = app.ComplexityData1(1);
            app.SymmetryEditField.Value = app.SymmetryData1(1);
            app.PeakEditField.Value = app.PeakData1(1);
            app.ValleyEditField.Value = app.ValleyData1(1);
            
            %Create dictionnary of keywords and matched feature arrays
            map_keys = {'noise', 'up', 'down', 'flat', 'complex', 'symmetric', 'peak','valley','.'};
            map_vals = {app.NoiseData1, app.PosSlopeData1, app.NegSlopeData1, app.FlatData1, app.ComplexityData1, app.SymmetryData1, app.PeakData1, app.ValleyData1, zeros(1, size(app.YData, 1))};
            app.X_feat = containers.Map(map_keys, map_vals);

            %create matrix of scores for each signal up to a length of 4
            app.SCORES = zeros(size(app.YData, 1), 4);
        end
        
        
        function com_idx = union_cell_array(~, cell_array)
            if length(cell_array)==1
                com_idx = cell_array{1};
            elseif(length(cell_array)==2)
                [com_idx, pos] = intersect(cell_array{1}, cell_array{2});
            else
                [com_idx_i, pos] = intersect(cell_array{1}, cell_array{2});
                for i = 3:length(cell_array)
                    [com_idx_i, pos] = intersect(com_idx_i, cell_array{i});
                end
                com_idx = com_idx_i;
            end
        end

        
        function sliced_s = slice_with_overlap(~, s, len_k)
        %Returns a 2D array with each row being one window of s with specified
        %overlap
            win_size = fix(length(s)/len_k);
            overlap = fix(0.5*win_size);
            sliced_s = s((0:overlap:end-win_size)'+(1:win_size));
        end

        function results = disable_edit_fields(app)
            app.NoiseEditField.Enable = "off";
            app.NoiseEditField_2.Enable = "off";
            app.NoiseEditField_3.Enable = "off";
            app.NoiseEditField_4.Enable = "off";

            app.UpEditField.Enable = "off";
            app.UpEditField_2.Enable = "off";
            app.UpEditField_3.Enable = "off";
            app.UpEditField_4.Enable = "off";

            app.DownEditField.Enable = "off";
            app.DownEditField_2.Enable = "off";
            app.DownEditField_3.Enable = "off";
            app.DownEditField_4.Enable = "off";

            app.ComplexityEditField.Enable = "off";
            app.ComplexityEditField_2.Enable = "off";
            app.ComplexityEditField_3.Enable = "off";
            app.ComplexityEditField_4.Enable = "off";

            app.SymmetryEditField.Enable = "off";
            app.SymmetryEditField_2.Enable = "off";
            app.SymmetryEditField_3.Enable = "off";
            app.SymmetryEditField_4.Enable = "off";

            app.FlatEditField.Enable = "off";
            app.FlatEditField_2.Enable = "off";
            app.FlatEditField_3.Enable = "off";
            app.FlatEditField_4.Enable = "off";

            app.PeakEditField.Enable = "off";
            app.PeakEditField_2.Enable = "off";
            app.PeakEditField_3.Enable = "off";
            app.PeakEditField_4.Enable = "off";

            app.ValleyEditField.Enable = "off";
            app.ValleyEditField_2.Enable = "off";
            app.ValleyEditField_3.Enable = "off";
            app.ValleyEditField_4.Enable = "off";

            results = 0;
        end

        function results = enable_specific_field(app, key, index, sig_row)
            switch(key)
                case "noise"
                    switch index
                        case 1
                            app.NoiseEditField.Enable = "on";
                            app.NoiseEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.NoiseEditField_2.Enable = "on";
                            app.NoiseEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.NoiseEditField_3.Enable = "on";
                            app.NoiseEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.NoiseEditField_4.Enable = "on";
                            app.NoiseEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "up"
                    switch index
                        case 1
                            app.UpEditField.Enable = "on";
                            app.UpEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.UpEditField_2.Enable = "on";
                            app.UpEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.UpEditField_3.Enable = "on";
                            app.UpEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.UpEditField_4.Enable = "on";
                            app.UpEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "down"
                    switch index
                        case 1
                            app.DownEditField.Enable = "on";
                            app.DownEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.DownEditField_2.Enable = "on";
                            app.DownEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.DownEditField_3.Enable = "on";
                            app.DownEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.DownEditField_4.Enable = "on";
                            app.DownEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "complex"
                    switch index
                        case 1
                            app.ComplexityEditField.Enable = "on";
                            app.ComplexityEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.ComplexityEditField_2.Enable = "on";
                            app.ComplexityEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.ComplexityEditField_3.Enable = "on";
                            app.ComplexityEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.ComplexityEditField_4.Enable = "on";
                            app.ComplexityEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "symmetric"
                    switch index
                        case 1
                            app.SymmetryEditField.Enable = "on";
                            app.SymmetryEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.SymmetryEditField_2.Enable = "on";
                            app.SymmetryEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.SymmetryEditField_3.Enable = "on";
                            app.SymmetryEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.SymmetryEditField_4.Enable = "on";
                            app.SymmetryEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "flat"
                    switch index
                        case 1
                            app.FlatEditField.Enable = "on";
                            app.FlatEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.FlatEditField_2.Enable = "on";
                            app.FlatEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.FlatEditField_3.Enable = "on";
                            app.FlatEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.FlatEditField_4.Enable = "on";
                            app.FlatEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "peak"
                    switch index
                        case 1
                            app.PeakEditField.Enable = "on";
                            app.PeakEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.PeakEditField_2.Enable = "on";
                            app.PeakEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.PeakEditField_3.Enable = "on";
                            app.PeakEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.PeakEditField_4.Enable = "on";
                            app.PeakEditField_4.Value = app.SCORES(sig_row, index);
                    end
                case "valley"
                    switch index
                        case 1
                            app.ValleyEditField.Enable = "on";
                            app.ValleyEditField.Value = app.SCORES(sig_row, index);
                        case 2
                            app.ValleyEditField_2.Enable = "on";
                            app.ValleyEditField_2.Value = app.SCORES(sig_row, index);
                        case 3
                            app.ValleyEditField_3.Enable = "on";
                            app.ValleyEditField_3.Value = app.SCORES(sig_row, index);
                        case 4
                            app.ValleyEditField_4.Enable = "on";
                            app.ValleyEditField_4.Value = app.SCORES(sig_row, index);
                    end
            end
            results = 0;
        end


        function results = edit_field_update(app, sig_row)
            %disable all editfields
            r = app.disable_edit_fields;

            query = app.Query_EditField.Value;
            query = query(2:end-1);
            keywords = split(query);
            for i = 1:length(keywords)
                %check for operators on keyword
                if(contains(keywords(i), '!'))
                    keyword = lower(char(keywords(i)));
                    keyword = keyword(2:end);
                    search_op = 1;
                else
                    keyword = lower(char(keywords(i)));
                    search_op = 2;
                end

                res = app.enable_specific_field(keyword, i, sig_row);
            end
            results = 0;
        end
        
        function peak_measure = detect_peaks(~, s)
            [pks, lcs] = findpeaks(s, "MinPeakProminence", 0.1);
            peak_measure = length(pks);
        end


        %-------------------------------------------------------------------
        %   Loading methods
        %-------------------------------------------------------------------
        

        function sub_load_signals(app)
            app.loadKeywords();
            %update slider value based on size of the signal
            nbrSignals = size(app.XData, 1);
            app.SignalSlider.Limits = [1, nbrSignals];

            colors = colormap(jet(length(unique(app.Y))));


            app.CLASSES = unique(app.Y);
            app.COLORS = colors;
            
            for i = 1:size(app.XData, 1)/10
                plot(app.UIAxes, ((i-1)*size(app.XData,2)+1):i*size(app.XData, 2), app.XData(i,:), "Color", app.COLORS(app.Y(i), :))
                hold(app.UIAxes,"on")
            end
            hold(app.UIAxes, "off")

            %create rectangle Patch on plot
            rectWidth = size(app.XData, 2);
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [min(min(app.XData))-0.5, max(max(app.XData))+0.5])
            rectY = ylim(app.UIAxes);
            app.Patch = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), "r", "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, rectWidth*size(app.XData, 1)+rectWidth])

        end

        function loadKeywords(app)
            %load set of keywords with a moving window  (In this case, the
            %window is basically the size of the signal divided by 4)
            win_size = fix(size(app.XData, 2)/2);

            keywords_keys = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'high', 'low', 'peak', 'valley', 'stepup', 'stepdown', 'plateauup', 'plateaudown', 'top', 'bottom', 'stutter', 'quick', 'vval', 'uval', 'middle'};
            
            len_signal = size(app.XData, 2);
            nbr_signals = size(app.XData, 1);

            keywords_vals1 = cell(1, length(keywords_keys));
            keywords_vals2 = cell(1, length(keywords_keys));
%             keywords_vals3 = cell(1, length(keywords_keys));

            d1 = cell(nbr_signals, length(keywords_keys));
            d2 = cell(nbr_signals, length(keywords_keys));
%             d3 = cell(nbr_signals, length(keywords_keys));

            for i = 1:nbr_signals
                app.NoiseEditField.Value = i;
                %sig
                s = app.XData(i,:);
                
                %windo 1 = win_size
                [d1{i,1}, d1{i,2}, d1{i,3}, d1{i,4}, d1{i,6}, d1{i, 7}, d1{i, 8}, d1{i, 15}, d1{i, 16}, d1{i,18}, d1{i, 21}] = app.quick_moving_feature_extraction(s, win_size);
                [d1{i,5}, d1{i,17}, d1{i, 9}, d1{i, 10}] = app.slow_moving_feature_extraction(s, win_size);

%                 [~, d1{i, 9}] = app.gauss_peak_moving(s, 0.1, 2*win_size);
%                 [~, d1{i, 10}] = app.gauss_val_moving(s, 0.1, 2*win_size);
                
                [d1{i,11}, d1{i,12}] = app.moving_step(s, 2*win_size);
                [d1{i,13}, d1{i,14}] = app.moving_plateau(s, 2*win_size);

                d1{i,19} = app.v_gauss_val_moving(s, 2*win_size);
                d1{i,20} = app.u_gauss_val_moving(s, 2*win_size);

                %window 2 = win_size/4
                [d2{i,1}, d2{i,2}, d2{i,3}, d2{i,4}, d2{i,6}, d2{i, 7}, d2{i, 8}, d2{i, 15}, d2{i, 16}, d2{i,18}, d2{i,21}] = app.quick_moving_feature_extraction(s, fix(win_size/4));
                [d2{i,5}, d2{i,17}, d2{i, 9}, d2{i, 10}] = app.slow_moving_feature_extraction(s, fix(win_size/4));

%                 d2{i, 9} = d1{i, 9};
%                 d2{i, 10} = d1{i, 10};
                d2{i, 11} = d1{i, 11};
                d2{i, 12} = d1{i, 12};
                d2{i, 13} = d1{i, 13};
                d2{i, 14} = d1{i, 14};
                d2{i, 19} = d1{i, 19};
                d2{i, 20} = d1{i, 20};
%                 [~, d2{i, 9}] = app.gauss_peak_moving(s, 0.1, 2*fix(win_size));
%                 [~, d2{i, 10}] = app.gauss_val_moving(s, 0.1, fix(win_size));
%                 
%                 [d2{i,11}, d2{i,12}] = app.moving_step(s, fix(win_size));
%                 [d2{i,13}, d2{i,14}] = app.moving_plateau(s, fix(win_size));
% 
%                 d2{i,19} = app.v_gauss_val_moving(s, fix(win_size));
%                 d2{i,20} = app.u_gauss_val_moving(s, fix(win_size));

%                 %window 2 = win_size/16
%                 [d3{i,1}, d3{i,2}, d3{i,3}, d3{i,4}, d3{i,6}, d3{i, 7}, d3{i, 8}, d3{i, 15}, d3{i, 16}, d3{i,18}, d3{i, 21}] = app.quick_moving_feature_extraction(s, fix(win_size/16));
%                 [d3{i,5}, d3{i,17}] = app.slow_moving_feature_extraction(s, fix(win_size/16));
% 
%                 [~, d3{i, 9}] = app.gauss_peak_moving(s, 0.1, fix(win_size/4));
%                 [~, d3{i, 10}] = app.gauss_val_moving(s, 0.1, fix(win_size/4));
% 
%                 [d3{i,11}, d3{i,12}] = app.moving_step(s, fix(win_size/4));
%                 [d3{i,13}, d3{i,14}] = app.moving_plateau(s, fix(win_size/4));
% 
%                 d3{i,19} = app.v_gauss_val_moving(s, fix(win_size/4));
%                 d3{i,20} = app.u_gauss_val_moving(s, fix(win_size/4));
            end

            %map keywords and keys into a container, already normalized
            for i = 1:length(keywords_keys)
                keywords_vals1{i} = cell2mat(d1(:,i));
                max1=max(max(keywords_vals1{i})); min1 = min(min(keywords_vals1{i})); 
                keywords_vals1{i} = (keywords_vals1{i}-min1)/(max1-min1);

                keywords_vals2{i} = cell2mat(d2(:,i));
                max2=max(max(keywords_vals2{i})); min2 = min(min(keywords_vals2{i})); 
                keywords_vals2{i} = (keywords_vals2{i}-min2)/(max2-min2);

%                 keywords_vals3{i} = cell2mat(d3(:,i));
%                 max3=max(max(keywords_vals3{i})); min3 = min(min(keywords_vals3{i})); 
%                 keywords_vals3{i} = (keywords_vals3{i}-min3)/(max3-min3);
            end

            %map keywords and keys into a container, already normalized
            app.KEY_VECTOR1 = containers.Map(keywords_keys, keywords_vals1);
            app.KEY_VECTOR2 = containers.Map(keywords_keys, keywords_vals2);
%             app.KEY_VECTOR3 = containers.Map(keywords_keys, keywords_vals3);
        end

        function loadKeywords_multi(app)
            %load set of keywords with a moving window  (In this case, the
            %window is basically the size of the signal divided by 4)
            win_size = fix(size(app.XData, 2)/2);

            keywords_keys = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'high', 'low', 'peak', 'valley', 'stepup', 'stepdown', 'plateauup', 'plateaudown', 'top', 'bottom', 'stutter', 'quick', 'vval', 'uval', 'middle'};
            
            len_signal = size(app.XData, 2);
            nbr_signals = size(app.XData, 1);

            keywords_vals1 = cell(1, length(keywords_keys));
            keywords_vals2 = cell(1, length(keywords_keys));
%             keywords_vals3 = cell(1, length(keywords_keys));

            d1 = cell(nbr_signals, length(keywords_keys));
            d2 = cell(nbr_signals, length(keywords_keys));
%             d3 = cell(nbr_signals, length(keywords_keys));

            for i = 1:nbr_signals
                %sig
                s = app.XData(i,:);
                
                %windo 1 = win_size
                [d1{i,1}, d1{i,2}, d1{i,3}, d1{i,4}, d1{i,6}, d1{i, 7}, d1{i, 8}, d1{i, 15}, d1{i, 16}, d1{i,18}, d1{i, 21}] = app.quick_moving_feature_extraction(s, win_size);
                [d1{i,5}, d1{i,17}, d1{i, 9}, d1{i, 10}] = app.slow_moving_feature_extraction(s, win_size);

%                 [~, d1{i, 9}] = app.gauss_peak_moving(s, 0.1, 2*win_size);
%                 [~, d1{i, 10}] = app.gauss_val_moving(s, 0.1, 2*win_size);
                
                [d1{i,11}, d1{i,12}] = app.moving_step(s, 2*win_size);
                [d1{i,13}, d1{i,14}] = app.moving_plateau(s, 2*win_size);

                d1{i,19} = app.v_gauss_val_moving(s, 2*win_size);
                d1{i,20} = app.u_gauss_val_moving(s, 2*win_size);

                %window 2 = win_size/4
                [d2{i,1}, d2{i,2}, d2{i,3}, d2{i,4}, d2{i,6}, d2{i, 7}, d2{i, 8}, d2{i, 15}, d2{i, 16}, d2{i,18}, d2{i,21}] = app.quick_moving_feature_extraction(s, fix(win_size/2));
                [d2{i,5}, d2{i,17}, d2{i, 9}, d2{i, 10}] = app.slow_moving_feature_extraction(s, fix(win_size/2));

%                 d2{i, 9} = d1{i, 9};
%                 d2{i, 10} = d1{i, 10};
                d2{i, 11} = d1{i, 11};
                d2{i, 12} = d1{i, 12};
                d2{i, 13} = d1{i, 13};
                d2{i, 14} = d1{i, 14};
                d2{i, 19} = d1{i, 19};
                d2{i, 20} = d1{i, 20};
%                 [~, d2{i, 9}] = app.gauss_peak_moving(s, 0.1, 2*fix(win_size));
%                 [~, d2{i, 10}] = app.gauss_val_moving(s, 0.1, fix(win_size));
%                 
%                 [d2{i,11}, d2{i,12}] = app.moving_step(s, fix(win_size));
%                 [d2{i,13}, d2{i,14}] = app.moving_plateau(s, fix(win_size));
% 
%                 d2{i,19} = app.v_gauss_val_moving(s, fix(win_size));
%                 d2{i,20} = app.u_gauss_val_moving(s, fix(win_size));

%                 %window 2 = win_size/16
%                 [d3{i,1}, d3{i,2}, d3{i,3}, d3{i,4}, d3{i,6}, d3{i, 7}, d3{i, 8}, d3{i, 15}, d3{i, 16}, d3{i,18}, d3{i, 21}] = app.quick_moving_feature_extraction(s, fix(win_size/16));
%                 [d3{i,5}, d3{i,17}] = app.slow_moving_feature_extraction(s, fix(win_size/16));
% 
%                 [~, d3{i, 9}] = app.gauss_peak_moving(s, 0.1, fix(win_size/4));
%                 [~, d3{i, 10}] = app.gauss_val_moving(s, 0.1, fix(win_size/4));
% 
%                 [d3{i,11}, d3{i,12}] = app.moving_step(s, fix(win_size/4));
%                 [d3{i,13}, d3{i,14}] = app.moving_plateau(s, fix(win_size/4));
% 
%                 d3{i,19} = app.v_gauss_val_moving(s, fix(win_size/4));
%                 d3{i,20} = app.u_gauss_val_moving(s, fix(win_size/4));
            end

            %map keywords and keys into a container, already normalized
            for i = 1:length(keywords_keys)
                keywords_vals1{i} = cell2mat(d1(:,i));
                max1=max(max(keywords_vals1{i})); min1 = min(min(keywords_vals1{i}));
                keywords_vals1{i} = (keywords_vals1{i}-min1)/(max1-min1);

                keywords_vals2{i} = cell2mat(d2(:,i));
                max2=max(max(keywords_vals2{i})); min2 = min(min(keywords_vals2{i})); 
                keywords_vals2{i} = (keywords_vals2{i}-min2)/(max2-min2);

%                 keywords_vals3{i} = cell2mat(d3(:,i));
%                 max3=max(max(keywords_vals3{i})); min3 = min(min(keywords_vals3{i})); 
%                 keywords_vals3{i} = (keywords_vals3{i}-min3)/(max3-min3);
            end

            %map keywords and keys into a container, already normalized
            app.KEY_VECTOR1 = containers.Map(keywords_keys, keywords_vals1);
            app.KEY_VECTOR2 = containers.Map(keywords_keys, keywords_vals2);
%             app.KEY_VECTOR3 = containers.Map(keywords_keys, keywords_vals3);
        end
        
        %------------------------------------------------------------------
        % Normalization methods
        %------------------------------------------------------------------

        function norm_sig = norm_1_sig(~, s)
            if(max(s)==min(s))
                norm_sig = zeros(1, length(s));
            else
                norm_sig = (s - min(s))/(max(s)-min(s));
            end
        end


        function norm_sig = norm_1_sig_with_ref(~, s, min_, max_)
            norm_sig = (s - min_)/(max_ - min_);
        end

        %------------------------------------------------------------------
        % Keyword extraction methods
        %------------------------------------------------------------------

        function residual_sig = wbr(~, sig, win_size)
        %noise level, based on removing the baseline and getting the residuals
            filt_sig = movmean(sig, win_size);
            residual_sig = sig - filt_sig;
        end

        function residuals = cum_wbr(app, sig, win)
        %check how noisy is a signal by subtracting the wandering baseline
        %to the signal and summing the residuals values (need to check the
        %win value)
            res_sig = app.wbr(sig, win);
            residuals = sum(abs(res_sig));
        end

        function [up, down] = slope_estimation(~, sig)
        %Estimate the slope of a linear approximation to the signal.
        %Basically return m, from y = mx + b
            P = polyfit(linspace(0, length(sig), length(sig)), sig, 1);
            slope_ = P(1);

            if(slope_>0)
                up = slope_;
                down = 0;
            elseif(slope_<0)
                up = 0;
                down = abs(slope_);
            else
                up = 0;
                down = 0;
            end
        end
        
        function symmetry = symmetry_estimation(~, sig2search, query)
        %Search minimum of left matrix profile over the fliped signal.
        %The lower the value, the higher is the symmetry.
            mass_distance = MASS_V2(sig2search, query);
            if(isnan(min(mass_distance)))
                symmetry = 0;
            else
                symmetry = real(min(mass_distance));
            end
        end

        function complexity = complexity_estimation(~, sig)
        %Search how complex a signal is by estimating the absolute distance traveled
        %by the signal
            complexity = sqrt(sum(diff(sig).^2));
        end

        function throttle = stutter_estimation(~, sig, query)
            sig2search = [fliplr(sig(1:fix(length(sig)/10)+1)) sig fliplr(sig(fix(length(sig)/10)+1:end))];
            mass_distance = MASS_V2(sig2search, query);

            if(isnan(min(mass_distance)))
                throttle = 0;
            else
                throttle = real(min(mass_distance));
            end
%             throttle = norm(sig - query);
        end

        function [uncommon, common] = MP(app, s, win_size)
            if(win_size<4)
                win_size = 4;
            end
            [uncommon, ~] = mstamp(transpose(s), fix(win_size), [], []);
            uncommon = app.norm_1_sig(transpose(uncommon(1:length(s)-win_size-1)));
            common = 1 - uncommon;
        end

        %------------------------------------------------------------------
        % Sliding Method to retrieve moving keywords
        %------------------------------------------------------------------
        function [moving_noise, moving_up, moving_down, moving_flat, moving_complexity, moving_high, moving_low, moving_top, moving_bottom, moving_speed, moving_middle] = quick_moving_feature_extraction(app, sig, win_size)
            
            half_win_size = fix(win_size/2);

%             mirrored_sig = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size+1:end))];
            mirrored_sig = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];
            mirror_sig_mat = buffer(mirrored_sig, win_size, win_size-1, 'nodelay');
            mirror_sig_mat = mirror_sig_mat(:, 1:length(sig));

            moving_noise = movstd(sig, win_size);

            moving_complexity = sqrt(movsum(diff(sig).^2, win_size));
            moving_complexity = [moving_complexity, moving_complexity(end)];
            
            moving_high = movmax(sig, win_size) - movmin(sig, win_size);
            moving_low = max(moving_high)-moving_high;
            
            moving_top = movmean(sig, win_size);

            moving_middle = abs(sig - movmean(sig, win_size));
            moving_middle = max(moving_middle) - moving_middle;

            moving_bottom = max(moving_top)-moving_top;

            moving_speed = movsum(abs(diff(sig)), win_size);
            moving_speed = [moving_speed, moving_speed(end)];

            x_mat = [ones(win_size, 1), transpose(linspace(1, win_size, win_size))];
            
            slopes_ = x_mat\mirror_sig_mat;
            slopes_ = slopes_(2, :);

            moving_up = max(slopes_, 0);
            moving_down = abs(min(slopes_, 0));

%             moving_flat = 1-app.norm_1_sig(abs(slopes_));
%             moving_flat = 1 - app.norm_1_sig(movsum(abs(sig), win_size) - movmean(sig, win_size));
            moving_flat = sum(abs(mirror_sig_mat - mean(mirror_sig_mat, 1)), 1);
            moving_flat = max(moving_flat) - moving_flat;
        end

        function [moving_symmetry, moving_stutter, moving_peak, moving_valley] = slow_moving_feature_extraction(app, sig, win_size)

        %Moving window keywords extraction method. It returns a signal for
        %each keyword with the same size of the original signal, because it
        %mirrors the original signal with half the size of the defined
        %window. This process also prevents border issues.
            
            half_win_size = fix(win_size/2);
%             mirrored_sig = [fliplr(sig(1:win_size)), sig, fliplr(sig(end-win_size-half_win_size:end))];
            mirrored_sig = [sig(1)*ones(1, win_size), sig, sig(end)*ones(1, win_size+half_win_size)];
            
            moving_symmetry = zeros(1, length(sig));
            moving_stutter = zeros(1, length(sig));
            moving_peak = zeros(1, length(sig));
            moving_valley = zeros(1, length(sig));
            
            for i = win_size:length(sig)+win_size-1
                sec_mirrored_sig = mirrored_sig(i-half_win_size+1:i+half_win_size);
                sig2search_symmetry = mirrored_sig(i-win_size+1:i+win_size);
                %extract features
                %Symmetry--------------------------------------------------------------------------
                moving_symmetry(i-win_size+1) = app.symmetry_estimation(sig2search_symmetry, fliplr(sec_mirrored_sig));
                [peak_up1, val_down1] = app.slope_estimation(sec_mirrored_sig(1:length(sec_mirrored_sig)/2));
                [val_up2, peak_down2] = app.slope_estimation(sec_mirrored_sig(length(sec_mirrored_sig)/2:end));
                %Up, Down and Flat-----------------------------------------------------------------
                moving_peak(i-win_size+1) = peak_up1*peak_down2;
                moving_valley(i-win_size+1) = val_down1*val_up2;
%                 section of the signal that will be used to compare with
%                 the previous section and understand if a pattern is
%                 repeated in time. If so, 
                sec_prev_window = mirrored_sig(i-win_size+1:i);
                sec_current_window = mirrored_sig(i-fix(win_size/4):i+win_size+fix(win_size/4));
                moving_stutter(i-win_size+1) = app.stutter_estimation(sec_current_window, sec_prev_window);
            end            
            
            
            %invert symmetry to correctly assign value association
            moving_symmetry = max(moving_symmetry) - moving_symmetry;

            %Is the next window very similar in shape with the current one?
            moving_stutter = max(moving_stutter) - moving_stutter;
        end

        function [query_peak, mass_peak] = gauss_peak_moving(app, sig, c, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            query_peak = a*exp(((x-b).^2)/(-2*(c^2)));
            mass_peak = log(MASS_V2(sig_, query_peak));
            mass_peak(isinf(mass_peak)) = max(mass_peak(~isinf(mass_peak)));
            mass_peak(isnan(mass_peak)) = max(mass_peak(~isnan(mass_peak)));
            mass_peak = max(mass_peak) - mass_peak(1:length(sig));
        end
        
        function [valley_, mass_valley] = gauss_val_moving(app, sig, c, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(c^2)));
            mass_valley = log(MASS_V2(sig_, valley_));
            mass_valley(isinf(mass_valley)) = max(mass_valley(~isinf(mass_valley)));
            mass_valley(isnan(mass_valley)) = max(mass_valley(~isnan(mass_valley)));
            mass_valley = max(mass_valley) - mass_valley(1:length(sig));
        end

        function mass_v_valley = v_gauss_val_moving(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];
            a = 1;
            b = 0;
            
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(0.05^2)));
            
            mass_v_valley = log(MASS_V2(sig_, valley_));
            mass_v_valley(isinf(mass_v_valley)) = max(mass_v_valley(~isinf(mass_v_valley)));
            mass_v_valley(isnan(mass_v_valley)) = max(mass_v_valley(~isnan(mass_v_valley)));
            mass_v_valley = max(mass_v_valley) - mass_v_valley(1:length(sig));
        end

        function mass_u_valley = u_gauss_val_moving(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(0.5^2)));
            mass_u_valley = log(log(MASS_V2(sig_, valley_)));
            mass_u_valley(isinf(mass_u_valley)) = max(mass_u_valley(~isinf(mass_u_valley)));
            mass_u_valley(isnan(mass_u_valley)) = max(mass_u_valley(~isnan(mass_u_valley)));
            mass_u_valley = max(mass_u_valley) - mass_u_valley(1:length(sig));
        end

        function [pos_step, neg_step] = moving_step(~, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];

            pos_step_query = zeros(1, win_size);
            neg_step_query = zeros(1, win_size);

            pos_step_query(win_size/2:end) = 1;
            neg_step_query(win_size/2:end) = -1;

            pos_step = MASS_V2(sig_, pos_step_query);
            pos_step(isinf(pos_step)) = max(pos_step(~isinf(pos_step)));
            pos_step(isnan(pos_step)) = max(pos_step(~isnan(pos_step)));
            pos_step = max(pos_step) - pos_step(1:length(sig));
            
            neg_step = MASS_V2(sig_, neg_step_query);
            neg_step(isinf(neg_step)) = max(neg_step(~isinf(neg_step)));
            neg_step(isnan(neg_step)) = max(neg_step(~isnan(neg_step)));
            neg_step = max(neg_step) - neg_step(1:length(sig));            
        end
        
        function [pos_plateau, neg_plateau] = moving_plateau(~, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [sig(1)*ones(1, half_win_size), sig, sig(end)*ones(1, half_win_size)];

            pos_plat_query = zeros(1, win_size);
            neg_plat_query = zeros(1, win_size);

            pos_plat_query(fix(win_size/10):9*fix(win_size/10)) = 1;
            neg_plat_query(fix(win_size/10):9*fix(win_size/10)) = -1;

            pos_plateau = MASS_V2(sig_, pos_plat_query);
            pos_plateau(isinf(pos_plateau)) = max(pos_plateau(~isinf(pos_plateau)));
            pos_plateau(isnan(pos_plateau)) = max(pos_plateau(~isnan(pos_plateau)));
            pos_plateau = max(pos_plateau) - pos_plateau(1:length(sig));

            neg_plateau = MASS_V2(sig_, neg_plat_query);
            neg_plateau(isinf(neg_plateau)) = max(neg_plateau(~isinf(neg_plateau)));
            neg_plateau(isnan(neg_plateau)) = max(neg_plateau(~isnan(neg_plateau)));
            neg_plateau = max(neg_plateau) - neg_plateau(1:length(sig));
        end

        %------------------------------------------
        %   Query search methods
        %------------------------------------------

        function [scores, signal_groups] = single_level_search(app, query)
            % 2 - check if single, or multiple signal
            [sig_indexs, signal_groups] = regexp(query, "s\d:","match", "split");
            
            % 3 - apply regex to query, separating brackets and single, now
            %also taking care of multisignals
            
            %If there are multiple signals being queried
            if(length(signal_groups)>1)
                if(contains(signal_groups(2), "followed by"))
                    %If there is a "followed by" statement for multiple
                    %signals, a different approach should be made
                    group1 = split(signal_groups(2), "followed by");
                    group2 = signal_groups(3);
                    scores = app.norm_1_sig(app.multi_query_search_followedby([group1(1), group2], sig_indexs));
                %If normal interaction between signals, than it is the
                %normal addition of scores
                else
                    scores = app.multi_query_search(signal_groups, sig_indexs);
                end
            else
                % 1 - Check the presence of sequence operator
                [followed_by_match, remaining_individual_queries] = regexp(query, "\w+ followed by \w+","match", "split");
                %check scores for followed by
                score_fb = zeros(size(app.XData, 1), 1);
                if(size(followed_by_match, 1)>0)
                    score_fb = app.norm_1_sig(app.followed_by_search(followed_by_match));
                    query = strjoin(remaining_individual_queries);
                else
                    query = signal_groups;
                end
                
                scores = app.uni_query_search(char(query), 1);
                scores = scores + score_fb;
            end

            scores = app.norm_1_sig(scores);
        end

        function scores = uni_query_search(app, query_group, ind)
           %keywords
            [bracket_groups, keywords_groups] = regexp(query_group, "\[.+?\]", "match", "split");
            scores = zeros(app.SIZE_DIMENSION, 1);
            %2 - calculate scores for each bracket group and single keyword
            %2.1 - start with brackets
            for bracket_i = bracket_groups
                %convert 2 char
                bracket_content = char(bracket_i);
                %calculate score and add to the previous array
                scores = scores + app.windowed_query_scores(bracket_content(2:end-1), ind);
            end
            
            %2.2 - calculate scores for each singular keyword
            merged_groups = strjoin(keywords_groups);
            splitted_keywords = split(merged_groups, " ");
            for i = 1:length(splitted_keywords)
                if(size(char(splitted_keywords(i)),2)==0)
                    continue
                else
                    keyword_score = app.single_keyword_score_estimation(splitted_keywords(i), ind);
                    scores = scores + keyword_score;
                end
            end
        end

        function scores_sig_i = multi_query_search(app, query_groups, signal_indxs)
            win_size = fix(size(app.XData, 2)/2);
            scores_sig_i = zeros(app.SIZE_DIMENSION, 1);
            for i = 1:length(query_groups)-1
                %get the index of the signal you will search the query in
                signal_i = str2num(char(regexp(char(signal_indxs(i)), "\d", 'match')));
                %search the query on the previous signal's index
                scores_sig_i = scores_sig_i + app.norm_1_sig(app.uni_query_search(char(query_groups(i+1)), signal_i));
            end
        end

        function scores = extract_keywords_score(app, keywords, ind)

            scores = zeros(app.SIZE_DIMENSION, length(keywords));
            
            if(length(keywords)<3)
                key_vector = app.KEY_VECTOR1;
            elseif length(keywords)>2
                key_vector = app.KEY_VECTOR2;
            end
            
            len_segments = fix(size(app.XData, 2)/length(keywords));
            
            for i = 1:length(keywords)
                %check for operators on keyword
                if(contains(keywords(i), '!'))
                    keyword = lower(char(keywords(i)));
                    keyword = keyword(2:end);
                    search_op = 1;
                else
                    keyword = lower(char(keywords(i)));
                    search_op = 2;
                end
                
                start_index = fix(app.SIZE_DIMENSION*(ind-1))+1;
                end_index = fix(app.SIZE_DIMENSION*ind);
                %Check which keyword is written
                if(keyword==".")
                    param_vec = zeros(app.SIZE_DIMENSION, 1);
                else
                    data_i = key_vector(keyword);
                    param_vec = (mean(data_i(start_index:end_index, (i-1)*len_segments+1:i*len_segments), 2));
                end

                if(search_op == 1)
                    scores(:, i) = 1 - param_vec;
                elseif(search_op == 2)
                    scores(:, i) = param_vec;
                end

            end
            scores = app.norm_1_sig(mean(scores, 2));
        end

        function norm_mean_scores = windowed_query_scores(app, window_query, ind)
            keywords = split(window_query);
            
            %extract keywords for each signal, subwindowed and normalized
            norm_mean_scores = app.extract_keywords_score(keywords, ind);
        end

        function single_keyword_score = single_keyword_score_estimation(app, keyword, ind)
            %check for operators on keyword
            start_index = fix(app.SIZE_DIMENSION*(ind-1))+1;
            end_index = fix(app.SIZE_DIMENSION*ind);
            
            if(contains(keyword, '!'))
                keyword = lower(char(keyword));
                keyword = keyword(2:end);
                data_i = app.KEY_VECTOR1(keyword);
                single_keyword_score = 1 - app.norm_1_vec(mean(data_i(start_index:end_index, :), 2));
            else
                keyword = lower(char(keyword));
                data_i = app.KEY_VECTOR1(keyword);
                single_keyword_score = app.norm_1_vec(mean(data_i(start_index:end_index, :), 2));
            end
        end

        function norm_vec = norm_1_vec(~, s)
            norm_vec = (s-min(s))/(max(s)-min(s));
        end

        function recreatePatchwindow(app, changingValue)
            sig_len = size(app.XData, 2);

            rectWidth = size(app.XData, 2);
            rectX = sig_len*(changingValue-1) + rectWidth*[0, 1];
            ylim(app.UIAxes, [min(min(app.XData))-0.5, max(max(app.XData))+0.5])
            rectY = ylim(app.UIAxes);
            app.Patch = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), "r", "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, rectWidth*size(app.XData, 1)+rectWidth])
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function loadSignal(app, event)
            UCR_name = app.UCR_load_EditField.Value;
            
            if(UCR_name == "multi_UWave")
                [y, X] = app.loadUCR_multi(UCR_name);
                app.XData = X;
                app.sub_load_signals();
                temp_classes = unique(y);
                %Just to be sure that classes go from 1 to N
                for y_i = 1:length(temp_classes)
                    app.Y(y==temp_classes(y_i)) = y_i;
                end
                colors = colormap(jet(length(unique(app.Y))));
                app.CLASSES = unique(app.Y);
                app.COLORS = colors;
            elseif(UCR_name == "load_UWaveKV")
                [y, X] = app.loadUCR_multi(UCR_name);
                app.XData = X;
                app.KEY_VECTOR1 = load("KEYVECTOR1_UWAVEALL.mat").key_vector;
                app.KEY_VECTOR2 = load("KEYVECTOR2_UWAVEALL.mat").key_vector;
                app.SIZE_DIMENSION = fix(size(X, 1)/3);
                app.NBR_DIMENSIONS = 3;
                temp_classes = unique(y);
                %Just to be sure that classes go from 1 to N
                for y_i = 1:length(temp_classes)
                    app.Y(y==temp_classes(y_i)) = y_i;
                end
                colors = colormap(jet(length(unique(app.Y))));
                app.CLASSES = unique(app.Y);
                app.COLORS = colors;
            else
                [y, X] = app.loadUCR(UCR_name);
                app.XData = X;
                app.SIZE_DIMENSION = size(X, 1);
                app.NBR_DIMENSIONS = 1;
                temp_classes = unique(y);
                %Just to be sure that classes go from 1 to N
                for y_i = 1:length(temp_classes)
                    app.Y(y==temp_classes(y_i)) = y_i;
                end
                colors = colormap(jet(length(unique(app.Y))));
                app.CLASSES = unique(app.Y);
                app.COLORS = colors;
                app.sub_load_signals();
            end

            
            %load keywords
%             app.load_keywords(X)
            %total size of signals concatenated
%             len_X = size(X,1)*size(X,2);
            %update slider value based on the number of signals loaded


% 
%             %In this case, there are no classes...
%             colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
%             app.COLORS = colors;  
%             %we have signal now!
%             app.ITER = 1;
%             app.SIGNAL = X;
%             %load keyword representation of the signal extracted in
%             %multiple dimensions

            
%             if(size(X, 1) > 1)
%                 app.sub_load_signals_multi();
%             else
%                 
%             end


        end

        % Value changing function: SignalSlider
        function UpdateSignalInfo(app, event)
            changingValue = fix(event.Value);
            
            nbr_signals = size(app.XData, 1);
            sig_len = size(app.XData, 2);
            
            rectX = sig_len*(changingValue-1) + sig_len*[0, 1];
            rectY = ylim(app.UIAxes);
            app.Patch.XData = rectX([1,2,2,1]);
            app.Patch.YData = rectY([1,1,2,2]);

            %Plot Sub Signal Section
            section = app.XData(changingValue,:);
            section2 = app.KEY_VECTOR1("noise");
            section3 = app.KEY_VECTOR1("up");
            section4 = app.KEY_VECTOR1("down");
            section5 = app.KEY_VECTOR1("complex");
            section6 = app.KEY_VECTOR1("symmetric");
            section7 = app.KEY_VECTOR1("flat");
            section8 = app.KEY_VECTOR1("peak");
            section9 = app.KEY_VECTOR1("valley");
            
            plot(app.UIAxes2, section + 12)
            hold(app.UIAxes2,"on")
            plot(app.UIAxes2, section9(changingValue,:))
            plot(app.UIAxes2, section8(changingValue,:) + 1.5)
            plot(app.UIAxes2, section5(changingValue,:) + 3)
            plot(app.UIAxes2, section6(changingValue,:) + 4.5)
            plot(app.UIAxes2, section7(changingValue,:) + 6)
            plot(app.UIAxes2, section4(changingValue,:) + 7.5)
            plot(app.UIAxes2, section3(changingValue,:) + 9)
            plot(app.UIAxes2, section2(changingValue,:) + 10.5)
            hold(app.UIAxes2,"off")

            %Plot Sub Signal Section
            section = app.XData(changingValue,:);
            section2 = app.KEY_VECTOR2("noise");
            section3 = app.KEY_VECTOR2("up");
            section4 = app.KEY_VECTOR2("down");
            section5 = app.KEY_VECTOR2("complex");
            section6 = app.KEY_VECTOR2("symmetric");
            section7 = app.KEY_VECTOR2("flat");
            section8 = app.KEY_VECTOR2("peak");
            section9 = app.KEY_VECTOR2("valley");
            
            plot(app.UIAxes2_2, section + 12)
            hold(app.UIAxes2_2,"on")
            plot(app.UIAxes2_2, section9(changingValue,:))
            plot(app.UIAxes2_2, section8(changingValue,:) + 1.5)
            plot(app.UIAxes2_2, section5(changingValue,:) + 3)
            plot(app.UIAxes2_2, section6(changingValue,:) + 4.5)
            plot(app.UIAxes2_2, section7(changingValue,:) + 6)
            plot(app.UIAxes2_2, section4(changingValue,:) + 7.5)
            plot(app.UIAxes2_2, section3(changingValue,:) + 9)
            plot(app.UIAxes2_2, section2(changingValue,:) + 10.5)
            hold(app.UIAxes2_2,"off")

%             %Plot Sub Signal Section
%             section = app.XData(changingValue,:);
%             section2 = app.KEY_VECTOR3("noise");
%             section3 = app.KEY_VECTOR3("up");
%             section4 = app.KEY_VECTOR3("down");
%             section5 = app.KEY_VECTOR3("complex");
%             section6 = app.KEY_VECTOR3("symmetric");
%             section7 = app.KEY_VECTOR3("flat");
%             section8 = app.KEY_VECTOR3("peak");
%             section9 = app.KEY_VECTOR3("valley");
%             
%             plot(app.UIAxes2_Representation, section + 12)
%             hold(app.UIAxes2_Representation,"on")
%             plot(app.UIAxes2_Representation, section9(changingValue,:))
%             plot(app.UIAxes2_Representation, section8(changingValue,:) + 1.5)
%             plot(app.UIAxes2_Representation, section5(changingValue,:) + 3)
%             plot(app.UIAxes2_Representation, section6(changingValue,:) + 4.5)
%             plot(app.UIAxes2_Representation, section7(changingValue,:) + 6)
%             plot(app.UIAxes2_Representation, section4(changingValue,:) + 7.5)
%             plot(app.UIAxes2_Representation, section3(changingValue,:) + 9)
%             plot(app.UIAxes2_Representation, section2(changingValue,:) + 10.5)
%             hold(app.UIAxes2_Representation,"off")

%             %Noise Section
%             app.NoiseEditField.Value = app.NoiseData1(changingValue);
% 
%             %Slope Section
%             app.UpEditField.Value  = app.PosSlopeData1(changingValue);
%             app.DownEditField.Value = app.NegSlopeData1(changingValue);
% 
%             %Flat Section
%             app.FlatEditField.Value = app.FlatData1(changingValue);
%             
%             %Complexity Section
%             app.ComplexityEditField.Value = app.ComplexityData1(changingValue);
%             
%             %Symmetry Section
%             app.SymmetryEditField.Value = app.SymmetryData1(changingValue);
% 
%             %Peak Section
%             app.PeakEditField.Value = app.PeakData1(changingValue);
% 
%             %Valley Section
%             app.ValleyEditField.Value = app.ValleyData1(changingValue);
% 
%             %Update all fields
%             res = app.edit_field_update(changingValue);
            
        end

        % Button pushed function: SearchButton
        function KeywordSearch(app, event)
            %Parse text----------------------------------------------
            %   Idea 2 - Check if keywords with space or brackets
            %   indicating window -> Now using regex!!!
            %   Idea 3 (Now in version 4 of the app) - include followed
            %   by/next to/after/before (for now...only using 1 time and
            %   not multiple instances of that operator)
            %--------------------------------------------------------
            
            query = char(app.Query_EditField.Value);
%             index_ = app.SignalSlider.Value;
            
            % 1 - check if multi_part search or single part search:
            search_levels = size(query, 1);
            
            if(search_levels>1)
                %do multilevel search
                [scores, signal_groups] = app.multi_level_search(query);
            else
                %do singlelevel search
                [scores, signal_groups] = app.single_level_search(query);
            end
            y = app.Y;
            k = 10;
            
            class_2_quantify = str2num(app.Class2matchEditField.Value);
            %How many values of this class are there?
            nbr_y = length(y(y==class_2_quantify));
            
            [scores_sorted, arg_sorted] = sort(scores);
            arg_sorted = flip(arg_sorted);
            scores_sorted = app.SIZE_DIMENSION*app.norm_1_sig(flip(scores_sorted));
            k_y = y(arg_sorted);
            hold(app.UIAxes,'off');

            for i = 1:app.SIZE_DIMENSION
                ind_i = arg_sorted(i);
                score_i = scores_sorted(i);
                if(i<100)
                    for ii = 1:app.NBR_DIMENSIONS
                        plot(app.UIAxes, (ind_i-1)*size(app.XData,2)+1:ind_i*size(app.XData, 2), app.norm_1_sig(app.XData(ind_i+(ii-1)*app.SIZE_DIMENSION,:)) + 1.5*ii, "Color", app.COLORS(app.Y(ind_i+(ii-1)*app.SIZE_DIMENSION),:), 'LineWidth', 1)
%                         plot(app.UIAxes2_Representation, linspace(1.25*(ii-1), ii, size(app.XData, 2)), app.norm_1_sig(app.XData(ind_i+(ii-1)*app.SIZE_DIMENSION, :))+score_i, "Color", app.COLORS(app.Y(ind_i+(ii-1)*app.SIZE_DIMENSION),:), 'LineWidth', 2);
                        hold(app.UIAxes,"on")
                   
                    end
%                     else
%                         plot(app.UIAxes, ((ind_i-1)*size(app.XData,2)+1):ind_i*size(app.XData, 2), app.XData(ind_i,:), "Color", app.COLORS(app.Y(ind_i), :), 'LineWidth', 3)
                end
                if(app.Y(ind_i)==class_2_quantify)
                    plot(app.UIAxes2_Representation, linspace(0, 1, 2), [score_i, score_i], "Color", app.COLORS(app.Y(ind_i), :), 'LineWidth', 2);
                else
                    plot(app.UIAxes2_Representation, linspace(0, 1, 2), [score_i, score_i], "Color", [200 200 200]/255, 'LineWidth', 1);
                end
                xlim(app.UIAxes2_Representation, [-1, 1.5*app.NBR_DIMENSIONS])
                hold(app.UIAxes2_Representation,"on")  

%                     if(i<50)
%                         plot(app.UIAxes_2, ((i-1)*size(app.XData,2)+1):i*size(app.XData, 2), app.XData(ind_i,:), "Color", app.COLORS(app.Y(ind_i), :), 'LineWidth', 2)
%                         hold(app.UIAxes_2,"on")
%                     end
            end
            hold(app.UIAxes2_Representation,"off")

            hold(app.UIAxes_2,'off');

            app.recreatePatchwindow(app.SignalSlider.Value);

            %Calculate TP, FP, FN
            k_y_i = k_y(1:nbr_y);
            TP = length(k_y_i(k_y_i==class_2_quantify));
            FP = nbr_y-TP;
            FN = FP;

            Precision = TP/(TP+FP);
            Recall = TP/(TP+FN);
            
            app.TPEditField.Value = TP/nbr_y;
            app.FPEditField.Value = FP/nbr_y;
            app.FNEditField.Value = FN/nbr_y;
            app.PEditField.Value = Precision;
            app.REditField.Value = Recall;

%             %PLOT---------------------------------------------------------
%             %from the retrieved scores, display the segments found
%             %put scores in same dimension as SIGNAL
%             new_scores = zeros(1, length(scores)+win_size);
%             new_scores(fix(win_size/2):end-fix(win_size/2)-1) = scores;
%             x_scores = linspace(1, length(new_scores), length(new_scores));
%             half_win = fix(win_size/2);
% 
%             app.SCORE = new_scores;
%             
%             %3 - plot score function on axes
%             plot(app.UIAxes_2, new_scores)
% 
%             %4 Order by k-most representative windows
%             %5 highlight most important windows
%             %6 plot eveything in the corresponding plots
% %             k = app.K_events_EditField.Value;
%             k = 25;
%             arg_k = zeros(1, k);
%             hold(app.UIAxes,'off');
%             if(length(signal_groups)>1)
%                 for i = 1:size(app.XData, 1)
%                     plot(app.UIAxes, app.XData(i, :)+(i-1)*1.5, 'LineWidth', 1, 'Color', '#808080')
%                     hold(app.UIAxes, 'on');
%                 end
%             else
%                 plot(app.UIAxes, app.XData, 'LineWidth', 0.8, 'Color', '#808080')
%                 hold(app.UIAxes, 'on');
%             end
% 
%             cmap = colormap(cool(100));
%             
%             for k_i = 1:k
%                 %find max
%                 [max_ki, argmax_ki] = max(new_scores);
%                 %save index to plot around it
%                 arg_k(k_i) = fix(win_size/2) + argmax_ki;
%                 %remove the score and its surrounding 
%                 a = argmax_ki-half_win+1;
%                 b = argmax_ki+half_win;
%                 new_scores(a:b) = 0;
%                 %highlight on plot
%                 if(length(signal_groups)>1)
%                     for i = 1:size(app.XData, 1)
%                         %plot subsequences on signal
%                         plot(app.UIAxes, x_scores(a:b), app.XData(i, a:b)+(i-1)*1.5, 'Color', cmap(fix(max_ki*100), :), 'LineWidth', 2)
%                         %plot ordered on side
%                         x_i = linspace(-1+i, i-0.5, length(a:b));
%                         plot(app.UIAxes_OrderedOutput, x_i, app.XData(i, a:b)+(k-k_i)*max_ki, 'LineWidth', 2, 'Color', cmap(fix(max_ki*100), :))
%                         hold(app.UIAxes_OrderedOutput, 'on')
%                     end
%                     
%                 else
%                     plot(app.UIAxes, x_scores(a:b), app.XData(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
%                     x_i = linspace(0, 0.5, length(a:b));
%                     plot(app.UIAxes_OrderedOutput, x_i, app.XData(a:b)+(k-k_i), 'LineWidth', 2)
%                     hold(app.UIAxes_OrderedOutput, 'on')
%                 end
%                 %keep searching the next higher window
%             end
%             
%             xlim(app.UIAxes_OrderedOutput, [-0.5, size(app.XData, 1)])
%             hold(app.UIAxes_OrderedOutput, 'off')
%             
%             if(length(signal_groups)>1)
%                 app.recreatePatchwindow_multi(app.SignalSlider.Value);
%             else
%                 app.recreatePatchwindow(app.SignalSlider.Value);
%             end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case "rightarrow"
                    app.SignalSlider.Value = app.SignalSlider.Value + 1;
                    app.UpdateSignalInfo(app.SignalSlider);
                case "leftarrow"
                    app.SignalSlider.Value = app.SignalSlider.Value - 1;
                    app.UpdateSignalInfo(app.SignalSlider);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2_Representation = uiaxes(app.UIFigure);
            app.UIAxes2_Representation.XTick = [];
            app.UIAxes2_Representation.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadSignal, true);
            app.LoadButton.Text = 'Load';
            app.UCR_load_EditField = uieditfield(app.UIFigure, 'text');
            app.UCR_load_EditField.Value = 'Trace';
            app.Query_EditField = uieditfield(app.UIFigure, 'text');
            app.Query_EditField.Placeholder = 'Enter query...';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @KeywordSearch, true);
            app.SearchButton.Text = 'Search';
            app.OverallResultsPanel = uipanel(app.UIFigure);
            app.OverallResultsPanel.Title = 'Overall Results';
            app.TPEditFieldLabel = uilabel(app.OverallResultsPanel);
            app.TPEditFieldLabel.Text = 'TP';
            app.TPEditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.FPEditFieldLabel = uilabel(app.OverallResultsPanel);
            app.FPEditFieldLabel.Text = 'FP';
            app.FPEditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.TNEditFieldLabel = uilabel(app.OverallResultsPanel);
            app.TNEditFieldLabel.Text = 'TN';
            app.TNEditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.FNEditFieldLabel = uilabel(app.OverallResultsPanel);
            app.FNEditFieldLabel.Text = 'FN';
            app.FNEditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.PEditFieldLabel = uilabel(app.OverallResultsPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.REditFieldLabel = uilabel(app.OverallResultsPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.OverallResultsPanel, 'numeric');
            app.KeywordInfoforSpecificWindowPanel = uipanel(app.UIFigure);
            app.KeywordInfoforSpecificWindowPanel.Title = 'Keyword Info for Specific Window';
            app.UIAxes2 = uiaxes(app.KeywordInfoforSpecificWindowPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.KeywordInfoforSpecificWindowPanel);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.NoiseEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.UpEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.UpEditFieldLabel.Text = 'Up';
            app.UpEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.DownEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.DownEditFieldLabel.Text = 'Down';
            app.DownEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.SymmetryEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.SymmetryEditFieldLabel.Text = 'Symmetry';
            app.SymmetryEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ComplexityEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.ComplexityEditFieldLabel.Text = 'Complexity';
            app.ComplexityEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.FlatEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.FlatEditFieldLabel.Text = 'Flat';
            app.FlatEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.NoiseEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.UpEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.DownEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.SymmetryEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ComplexityEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.FlatEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.NoiseEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.UpEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.DownEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.SymmetryEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ComplexityEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.FlatEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.NoiseEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.UpEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.DownEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.SymmetryEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ComplexityEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.FlatEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.PeakEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.PeakEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.PeakEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.PeakEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ValleyEditFieldLabel = uilabel(app.KeywordInfoforSpecificWindowPanel);
            app.ValleyEditFieldLabel.Text = 'Valley';
            app.ValleyEditField = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ValleyEditField_2 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ValleyEditField_3 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.ValleyEditField_4 = uieditfield(app.KeywordInfoforSpecificWindowPanel, 'numeric');
            app.SignalSliderLabel = uilabel(app.UIFigure);
            app.SignalSliderLabel.Text = 'Signal';
            app.SignalSlider = uislider(app.UIFigure);
            app.SignalSlider.Limits = [1 100];
            app.SignalSlider.MajorTickLabels = {''};
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @UpdateSignalInfo, true);
            app.SignalSlider.MinorTicks = [];
            app.SignalSlider.Value = 1;
            app.Class2matchEditFieldLabel = uilabel(app.UIFigure);
            app.Class2matchEditFieldLabel.Text = 'Class 2 match';
            app.Class2matchEditField = uieditfield(app.UIFigure, 'text');
            app.Class2matchEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NL_UCR_DATA_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end