classdef QuoTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        load_EditField        matlab.ui.control.EditField
        LoadButton            matlab.ui.control.Button
        KeepButton            matlab.ui.control.Button
        SaveFile              matlab.ui.control.EditField
        Query_EditField       matlab.ui.control.TextArea
        K_events_EditField    matlab.ui.control.NumericEditField
        WinSize_EditField     matlab.ui.control.NumericEditField
        SearchButton          matlab.ui.control.Button
        SignalSlider          matlab.ui.control.Slider
        UpdateButton          matlab.ui.control.Button
        UIAxes_OrderedOutput  matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XALL %container with multichannel data, such as physionet
        SIGNAL % container with the signal
        MOVING_SIGNAL %contains the signal separated with the total overlap windows

        KEY_VECTOR1 % container with the representation of the signal over the set of keywords and window size 1
        KEY_VECTOR2 % container with the representation of the signal over the set of keywords and window size 1/4
        KEY_VECTOR3 %contianer with the representation of the signal over the set of keywords and window size 1/16

        COLORS % Colors used for windows

        PATCH % object to use the sliding window along the signal
        SCORE % score array

        ITER = 0; % Iterator that defines if it is on the beginning or end
        
        SYNONYM
        VOCAB

        OrderedScore % Score [0-1] of the found subsequences
        OrderedPosition % Position of the corresponding subsequence stored in OrderedScore
    end
    
    methods (Access = private)

        %------------------------------------------------------------------
        % Loading and Initialization Functions
        %------------------------------------------------------------------
        function [y, X] = example_0(app)
            s1 = zeros(1, 1000);
            s2 = zeros(1, 1000);
            
            s1(250:500) = linspace(1, 250, 251);
            s1(750:900) = linspace(1, 150, 151);
            s2(250:500) = sin(2*pi*linspace(1, 250, 251));

            app.XALL = [app.norm_1_sig(s1);
                app.norm_1_sig(s2)];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = example_1(app)
            %Enable Channel selection
            channel = 1;
            %load physionet data
            s = load("datasets\example1\PhysionetData.mat");
            app.XALL = s.Signals{channel}(3250:4000);
            y = 0;
            X = app.norm_1_sig(app.XALL);
        end

        function [y, X] = example_2(app)
            %Enable Channel selection
%             app.Channel_EditField.Enable = "on";
%             channel = app.Channel_EditField.Value;
            %load physionet data
            s = load("datasets\example2\bird_song_mfcc3.mat").mybird3;
            app.XALL = s(1:4000);
            y = 0;
            X = app.norm_1_sig(app.XALL);
        end

        function [y, X] = example_3(app)
            
            s = load("datasets\example3\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("datasets\example3\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");

            acc_x = transpose(movmean(s(1:71844, 2),10));
            acc_y = transpose(movmean(s(1:71844, 3), 10));
            acc_z = transpose(movmean(s(1:71844, 4), 10));

            gyr_z = transpose(movmean(s2(1:71844, 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;          
        end

        function [y, X] = example_4(app)
            
            s = load("datasets\example3\free_road\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-56-59.txt");
            s2 = load("datasets\example3\free_road\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-56-59.txt");
            
            acc_x = transpose(movmean(s(1:size(s,1), 2),10));
            acc_y = transpose(movmean(s(1:size(s,1), 3), 10));
            acc_z = transpose(movmean(s(1:size(s,1), 4), 10));

            gyr_x = transpose(movmean(s2(1:size(s,1), 2), 10));
            gyr_y = transpose(movmean(s2(1:size(s,1), 3), 10));
            gyr_z = transpose(movmean(s2(1:size(s,1), 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_x;
                gyr_y;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = example_5(app)
            s_norm = load("datasets\example4\normal_motorway.txt");
            s_aggressive = load("datasets\example4\aggressive_motorway.txt");
            

            acc_x = [transpose(s_norm(:, 6)), mean(s_norm(:, 6))*ones(1, 100), transpose(s_aggressive(:, 6))];
            acc_y = [transpose(s_norm(:, 7)), mean(s_norm(:, 7))*ones(1, 100), transpose(s_aggressive(:, 7))];
            acc_z = [transpose(s_norm(:, 8)), mean(s_norm(:, 8))*ones(1, 100), transpose(s_aggressive(:, 8))];


            app.XALL = [
                acc_x;
                acc_y;
                acc_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = example_6(app)
            s = load("datasets\example6\ecg_arrythmia_04.txt");
            ind_a = 30000;
            ind_b = 80000;
            s = s(ind_a:ind_b, :)-movmean(s(ind_a:ind_b, :), 500);
            
            ecg_1 = transpose(s(:, 1));
            ecg_2 = transpose(s(:, 2));
            ecg_3 = transpose(s(:, 3));
            ecg_4 = transpose(s(:, 4));
            ecg_5 = transpose(s(:, 5));

            app.XALL = [
                ecg_1;
                ecg_2;
                ecg_3;
                ecg_4;
                ecg_5;
                ];
            y = 0;
            X = app.XALL;
        end

        function [y, X] = example_7(app)
            s = load("datasets\example6\ecg_arrythmia_04.txt");
            ind_a = 235000;
            ind_b = 290000;
            s = s(ind_a:ind_b, :)-movmean(s(ind_a:ind_b, :), 500);
            
            ecg_1 = transpose(s(:, 1));
            ecg_2 = transpose(s(:, 2));
            ecg_3 = transpose(s(:, 3));
            ecg_4 = transpose(s(:, 4));
            ecg_5 = transpose(s(:, 5));

            app.XALL = [
                ecg_1;
                ecg_2;
                ecg_3;
                ecg_4;
                ecg_5;
                ];
            y = 0;
            X = app.XALL;
        end

        function [y, X] = example_8(app)
            s = readtable("datasets\example7\participant_10.csv");
            ind_a = 114051;
%             ind_b = end;
            
            scr = transpose(s.SCR(ind_a:end));
            lateral = transpose(movmean(s.AccelX(ind_a:end), 10));
            longit = transpose(movmean(s.AccelZ(ind_a:end), 10));
            updown = transpose(movmean(s.AccelY(ind_a:end), 10));
            light = transpose(s.Lightning(ind_a:end));

            app.XALL = [
                updown;
                lateral;
                longit;
                light;
                scr;
                ];

            y = 0;
            X = app.XALL;
        end


        function plot_slider_init(app, single)
            %----------------------------------------------
            %   Slider Definition
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;
            %slider limits
            app.SignalSlider.Limits = [window_size, nbr_windows-window_size];
            
            %main plot
            hold(app.UIAxes, 'off');
            if(single)
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 1, 'Color', 'b')
            else
                for i = 1:size(app.SIGNAL, 1)
                    plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1)
                    hold(app.UIAxes, 'on')
                end
            end

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL, 1)])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, size(app.SIGNAL, 2)+rectWidth])

            %update plot
            if(single)
                app.updateSubSectionPlot(fix(window_size/2));
            else
                app.updateSubSectionPlot_multi(fix(window_size/2));
            end
        end

        function sub_load_signals(app)
            %load word_feature vectors
            [app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3] = word_feature_extraction(app.XALL, app.WinSize_EditField.Value);
            %plot
            app.plot_slider_init(1);
        end
        
        %------------------------------------------------------------------
        % Multi Dimensional methods
        %------------------------------------------------------------------

        function sub_load_signals_multi(app)
            %word feature vectors
            [app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3] = word_feature_extraction(app.XALL, app.WinSize_EditField.Value);
            %plot
            app.plot_slider_init(0)
        end

        function updateSubSectionPlot_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            %plot first signal
            section = app.SIGNAL(1, a:b);
            plot(app.UIAxes2, section)
        end


        %------------------------------------------------------------------
        % Normalization methods
        %------------------------------------------------------------------

        function norm_sig = norm_1_sig(~, s)
            if(max(s)==min(s))
                norm_sig = zeros(1, length(s));
            else
                norm_sig = (s - min(s))/(max(s)-min(s));
            end
        end

        %------------------------------------------------------------------
        % Sliding Update methods
        %------------------------------------------------------------------

        function recreatePatchwindow(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, length(app.SIGNAL)+win_size])
            
        end
        
        function updateSubSectionPlot(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            if(size(app.SIGNAL,1)>1)
                section = app.SIGNAL(1, a:b);
            else
                section = app.SIGNAL(a:b);
            end
            plot(app.UIAxes2, section)
        end


        function recreatePatchwindow_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL,1)])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, size(app.SIGNAL, 2)+win_size])
        end
        
        function [synonym, vocab] = load_thesaurus(app)
            file_ = fopen("docs\thesaurus.txt");
            f_txt = textscan(file_,'%s','delimiter','\n');
            f_txt = f_txt{1};
            synonym = [];
            vocab = [];
            for i = 1:length(f_txt)
                row = split(f_txt{i}, ' ');
                synonym = [synonym row(1)];
                vocab = [vocab row(2)];
            end
            synonym = string(synonym);
            vocab = string(vocab);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %This function initializes the entire process by (1) loading the
        %signal and (2) pre-loading the signal representation through each
        %keyword based on the window size selected

            %Get the name of the dataset to load the data correctly
            dataset_name = app.load_EditField.Value;

            %Function to load the dataset based on the name given if UCR is
            %contained
            if(contains(dataset_name, "example_0"))
                [y, X] = app.example_0();
            elseif(contains(dataset_name, "example_1"))
                [y, X] = app.example_1();
            elseif(contains(dataset_name, "example_2"))
                [y, X] = app.example_2();
            elseif(contains(dataset_name, "example_3"))
                [y, X] = app.example_3();
            elseif(contains(dataset_name, "example_4"))
                [y, X] = app.example_4();
            elseif(contains(dataset_name, "example_5"))
                [y, X] = app.example_5();
            elseif(contains(dataset_name, "example_6"))
                [y, X] = app.example_6();
            elseif(contains(dataset_name, "example_7"))
                [y, X] = app.example_7();
            elseif(contains(dataset_name, "example_8"))
                [y, X] = app.example_8();
            end
            %In this case, there are no classes...
            colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
            app.COLORS = colors;  
            %we have signal now!
            app.ITER = 1;
            app.SIGNAL = X;
            
            %load thesaurus
            [app.SYNONYM, app.VOCAB] = app.load_thesaurus();
            %check words on vocabulary

            %load keyword representation of the signal extracted in
            %multiple dimensions
            if(size(X, 1) > 1)
                app.sub_load_signals_multi();
            else
                app.sub_load_signals();
            end
        end

        % Button pushed function: UpdateButton
        function LoadSignal(app, event)
            %update signal word feature vectors based on new size
            if(size(app.XALL, 1) > 1)
                app.sub_load_signals_multi();
            else
                app.sub_load_signals();
            end
        end

        % Value changing function: SignalSlider
        function SignalSliderValueChanging(app, event)
            %Get value from slider
            changingValue = fix(event.Value);
            
            if(changingValue+1>(length(app.SIGNAL)-app.WinSize_EditField.Value/2))
                changingValue = fix(length(app.SIGNAL)-app.WinSize_EditField.Value/2)-1;
            end

            %update plot
            app.updateSubSectionPlot(changingValue);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %Parse text----------------------------------------------
            
            query = char(app.Query_EditField.Value);
            win_size = app.WinSize_EditField.Value;
           
%             %check if words are available in our vocabulary
%             all_words = regexp(query, "\w+", "match");
%             %check if words are found on vocabulary
%             synonym_vocab = [];
%             exception_arr = ["followed", "by"];
%             
%             for w_i = 1:length(all_words)
%                 if(exception_arr.contains(all_words(w_i)))
%                    continue
%                 elseif(~isempty(regexp(char(all_words(w_i)), "s\d+","match")))
%                     continue
%                 else
%                     ind_ = find(strcmp(app.SYNONYM, all_words(w_i)));
%                     if(isempty(ind_))
%                         w_dist = [];
%                         for synonym_i = app.SYNONYM
%                             w_dist = [w_dist EditDistance(synonym_i, all_words(w_i))];
%                         end
%                         [arg, val] = min(w_dist);
%                         fig = uifigure;
%                         uialert(fig, 'Did you meant '+ app.SYNONYM(arg)+' , instead of ' + all_words(w_i) + "?", 'Wrong word');
%                         %replace with closest word
%                         ind_ = find(strcmp(app.SYNONYM, app.SYNONYM(arg)));
%                         query = regexprep(query, all_words(w_i), app.VOCAB(ind_));
%                     else
%                         %replace query with standard keywords
%                         ind_ = find(strcmp(app.SYNONYM, all_words(w_i)));
%                         query = regexprep(query, all_words(w_i), app.VOCAB(ind_));
%                     end
%                 end
%             end
            

            % 1 - check if multi_part search or single part search:
            scores = query_search(app.XALL, app.KEY_VECTOR1, app.KEY_VECTOR2, app.KEY_VECTOR3, query, win_size);
            
            %PLOT---------------------------------------------------------
            %from the retrieved scores, display the segments found
            %put scores in same dimension as SIGNAL
            new_scores = zeros(1, length(scores)+win_size);
            new_scores(fix(win_size/2):end-fix(win_size/2)-1) = scores;
            x_scores = linspace(1, length(new_scores), length(new_scores));
            half_win = fix(win_size/2);

            app.SCORE = new_scores;
            
            %3 - plot score function on axes
            plot(app.UIAxes_2, new_scores)

            %4 Order by k-most representative windows
            %5 highlight most important windows
            %6 plot eveything in the corresponding plots
            k = app.K_events_EditField.Value;
            arg_k = zeros(1, k);
            hold(app.UIAxes,'off');
            if(size(app.XALL,1)>1)
                for i = 1:size(app.SIGNAL, 1)
                    plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1, 'Color', '#808080')
                    hold(app.UIAxes, 'on');
                end
            else
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 0.8, 'Color', '#808080')
                hold(app.UIAxes, 'on');
            end

            cmap = colormap(jet(100));
            all_ks = [];
            all_scores = [];
            for k_i = 1:k
                %find max
                [max_ki, argmax_ki] = max(new_scores);
                %save index to plot around it
                arg_k(k_i) = fix(win_size/2) + argmax_ki;
                
                %remove the score and its surrounding 
                a = argmax_ki-half_win+1;
                b = argmax_ki+half_win;
                all_ks = [all_ks, argmax_ki];
                all_scores = [all_scores, max_ki];
                new_scores(a:b) = 0;
                %highlight on plot
                if(size(app.XALL,1)>1)
                    for i = 1:size(app.SIGNAL, 1)
                        %plot subsequences on signal
                        s_ = app.norm_1_sig(app.SIGNAL(i,:));
                        plot(app.UIAxes, x_scores(a:b), s_(a:b)+(i-1)*1.5, 'Color', cmap(fix(max_ki*85), :), 'LineWidth', 2)
                        %plot ordered on side
                        x_i = linspace(-1+i, i-0.5, length(a:b));
                        plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i)*max_ki, 'LineWidth', 2, 'Color', cmap(fix(max_ki*85), :))
                        hold(app.UIAxes_OrderedOutput, 'on')
                    end
                    
                else
                    s_ = app.norm_1_sig(app.SIGNAL);
                    plot(app.UIAxes, x_scores(a:b), s_(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
                    x_i = linspace(0, 0.5, length(a:b));
                    plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i), 'LineWidth', 2)
                    hold(app.UIAxes_OrderedOutput, 'on')
                end
                %keep searching the next higher window
            end
           
            app.OrderedPosition = all_ks;
            app.OrderedScore = all_scores;

            xlim(app.UIAxes_OrderedOutput, [-0.5, size(app.SIGNAL, 1)])
            hold(app.UIAxes_OrderedOutput, 'off')
            
            if(size(app.XALL, 1)>1)
                app.recreatePatchwindow_multi(app.SignalSlider.Value);
            else
                app.recreatePatchwindow(app.SignalSlider.Value);
            end

        end

        % Callback function
        function Channel_EditFieldValueChanged(app, event)
            value = app.Channel_EditField.Value;
            
            app.SIGNAL = app.norm_1_sig(app.XALL{value});

            %load keyword representation of the signal extracted in
            %multiple dimensions
            app.loadKeywords()
            
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;
            app.SignalSlider.Limits = [fix(window_size/2), nbr_windows-fix(window_size/2)];
            
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])

            %make first update on edit field scores and subplot
            %update edit field scores
            app.updateEditFieldsScores(fix(window_size/2));

            %update plot
            app.updateSubSectionPlot(fix(window_size/2));
        end

        % Value changed function: WinSize_EditField
        function WinSize_EditFieldValueChanged(app, event)
%             if(app.ITER > 0)
%                 window_size = app.WinSize_EditField.Value;
%                 %load keyword representation of the signal extracted in
%                 %multiple dimensions
%                 app.loadKeywords()
%         
%                 %In this case, there are no classes...
%                 colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
%                 app.COLORS = colors;                      
%                 
%                 hold(app.UIAxes, 'off');
%                 plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')
%         
%                 %create rectangle Patch on plot
%                 rectWidth = window_size;
%                 rectX = rectWidth*[0, 1];
%                 ylim(app.UIAxes, [-0.5, 1.5])
%                 rectY = ylim(app.UIAxes);
%                 app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
%                 xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])
%         
%                 %make first update on edit field scores and subplot
%                 %update edit field scores
%                 app.updateEditFieldsScores(fix(window_size/2));
%         
%                 %update plot
%                 app.updateSubSectionPlot(fix(window_size/2));
%             end
        end

        % Value changed function: K_events_EditField
        function K_events_EditFieldValueChanged(app, event)
%             k = app.K_events_EditField.Value;
%             win_size = app.WinSize_EditField.Value;
%             half_win = fix(win_size/2);
% 
%             new_scores = app.SCORE;
%             x_scores = linspace(1, length(app.SIGNAL), length(app.SIGNAL));
% 
%             arg_k = zeros(1, k);
%             hold(app.UIAxes,'off');
%             plot(app.UIAxes, app.SIGNAL, 'LineWidth', 0.8, 'Color', '#808080')
%             hold(app.UIAxes, 'on');
%             for k_i = 1:k
%                 %find max
%                 [max_ki, argmax_ki] = max(new_scores);
%                 %save index to plot around it
%                 arg_k(k_i) = half_win + argmax_ki;
%                 %remove the score and its surrounding 
%                 a = argmax_ki-half_win+1;
%                 b = argmax_ki+half_win;
%                 new_scores(a:b) = 0;
%                 %highlight on plot
%                 plot(app.UIAxes, x_scores(a:b), app.SIGNAL(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
%                 %keep searching the next higher window
%             end
%             
%             app.recreatePatchwindow(app.SignalSlider.Value);
            
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            file_data = [app.OrderedPosition; app.OrderedScore];
            name = app.SaveFile.Value;
            writematrix(file_data, name+".txt");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_OrderedOutput = uiaxes(app.UIFigure);
            app.UIAxes_OrderedOutput.XTick = [];
            app.UIAxes_OrderedOutput.YTick = [];
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignal, true);
            app.UpdateButton.Text = 'Update';
            app.SignalSlider = uislider(app.UIFigure);
            app.SignalSlider.Limits = [1 100];
            app.SignalSlider.MajorTickLabels = {''};
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @SignalSliderValueChanging, true);
            app.SignalSlider.MinorTicks = [];
            app.SignalSlider.Value = 1;
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.WinSize_EditField = uieditfield(app.UIFigure, 'numeric');
            app.WinSize_EditField.ValueChangedFcn = createCallbackFcn(app, @WinSize_EditFieldValueChanged, true);
            app.WinSize_EditField.Value = 50;
            app.K_events_EditField = uieditfield(app.UIFigure, 'numeric');
            app.K_events_EditField.ValueChangedFcn = createCallbackFcn(app, @K_events_EditFieldValueChanged, true);
            app.K_events_EditField.Value = 10;
            app.Query_EditField = uitextarea(app.UIFigure);
            app.Query_EditField.Placeholder = 'Enter query...';
            app.SaveFile = uieditfield(app.UIFigure, 'text');
            app.KeepButton = uibutton(app.UIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.load_EditField = uieditfield(app.UIFigure, 'text');
            app.load_EditField.Value = 'example_0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuoTS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end