classdef app_update4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KeepButton                matlab.ui.control.Button
        SaveFile                  matlab.ui.control.EditField
        Query_EditField           matlab.ui.control.TextArea
        K_events_EditField        matlab.ui.control.NumericEditField
        Channel_EditField         matlab.ui.control.NumericEditField
        WinSize_EditField         matlab.ui.control.NumericEditField
        load_EditField            matlab.ui.control.EditField
        ValleyEditField           matlab.ui.control.NumericEditField
        ValleyEditFieldLabel      matlab.ui.control.Label
        PeakEditField             matlab.ui.control.NumericEditField
        PeakEditFieldLabel        matlab.ui.control.Label
        FlatEditField             matlab.ui.control.NumericEditField
        FlatEditFieldLabel        matlab.ui.control.Label
        ComplexityEditField       matlab.ui.control.NumericEditField
        ComplexityEditFieldLabel  matlab.ui.control.Label
        SymmetryEditField         matlab.ui.control.NumericEditField
        SymmetryEditFieldLabel    matlab.ui.control.Label
        DownEditField             matlab.ui.control.NumericEditField
        DownEditFieldLabel        matlab.ui.control.Label
        UpEditField               matlab.ui.control.NumericEditField
        UpEditFieldLabel          matlab.ui.control.Label
        NoiseEditField            matlab.ui.control.NumericEditField
        NoiseEditFieldLabel       matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        SignalSlider              matlab.ui.control.Slider
        LoadButton                matlab.ui.control.Button
        UIAxes_OrderedOutput      matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XALL %container with multichannel data, such as physionet
        SIGNAL % container with the signal
        MOVING_SIGNAL %contains the signal separated with the total overlap windows

        KEY_VECTOR1 % container with the representation of the signal over the set of keywords and window size 1
        KEY_VECTOR2 % container with the representation of the signal over the set of keywords and window size 1/4
        KEY_VECTOR3 %contianer with the representation of the signal over the set of keywords and window size 1/16

        COLORS % Colors used for windows

        PATCH % object to use the sliding window along the signal
        SCORE % score array

        ITER = 0; % Iterator that defines if it is on the beginning or end


        OrderedScore % Score [0-1] of the found subsequences
        OrderedPosition % Position of the corresponding subsequence stored in OrderedScore
    end
    
    methods (Access = private)

        %------------------------------------------------------------------
        % Loading and Initialization Functions
        %------------------------------------------------------------------
        
        function [y, X] = loadUCR(app, name)
        %This function loads the UCR dataset by concatenating the signals
        %horizontally, creating a single signal

            %Disable Channel selection
            app.Channel_EditField.Enable = "off";
            %load dataset
            ucr_name_lst = split(name, '_');
            ucr_name = ucr_name_lst(2);
            data = importdata("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\UCR_data\Univariate_arff\"+ucr_name + "\"+ucr_name+"_TEST.txt", "\n");
            data = vertcat(data{:});
            data = str2num(data);
            
            n = fix(size(data, 1)/4);
            y = data(1:n, 1);
            X1 = data(1:n, 2:end);
            X = zeros(1, size(X1,1)*size(X1,2));
            for i = 1:length(X1(:,1))
                %simple smoothing function
                s_i = movmean(X1(i, :), 10);
                %add by column to create a single signal, normalized
                %between [0, 1]
                X((i-1)*size(X1,2)+1:i*size(X1,2)) = app.norm_1_sig(s_i);
            end
        end

        function [y, X] = loadPhysio(app, name)
            %Enable Channel selection
            app.Channel_EditField.Enable = "on";
            channel = app.Channel_EditField.Value;
            %load physionet data
            s = load("PhysionetData.mat");
            app.XALL = s.Signals;
            y = 0;
            X = app.norm_1_sig(app.XALL{channel});
        end

        function [y, X] = loadBird(app, name)
            %Enable Channel selection
%             app.Channel_EditField.Enable = "on";
%             channel = app.Channel_EditField.Value;
            %load physionet data
            s = load("UCR_data\bird_songs\bird_song_mfcc3.mat").mybird3;
            app.XALL = s(1:4000);
            y = 0;
            X = app.norm_1_sig(app.XALL);
        end

        function [y, X] = loadmultiCar(app, name)
            %Enable Channel selection-think about that later
%             app.Channel_EditField.Enable = "on";
%             channel = app.Channel_EditField.Value;
            %load multi_signal car
            path = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110175712-16km-D1-NORMAL1-SECONDARY";
            acc = load(path+"\RAW_ACCELEROMETERS.txt");
            gps = load(path+"\RAW_GPS.txt");
            duration = 0.4*length(acc(:,1));
            
%             change_lane_events = load(path+"\annotations.txt");

            
%             x_events = fix(10*(change_lane_events(:, 1) - acc(1, 1)));
           
            y_events = zeros(1, length(acc(:,5)));
%             y_events(x_events) = 1;

            x_acc = linspace(1, length(acc(:, 5)), length(acc(:,5)));
            x_gps = linspace(1, length(acc(:, 5)), length(gps(:,2)));
            gps = interp1(x_gps, gps(:,2), x_acc);
            z_axis = transpose(acc(:, 8));
            y_axis = transpose(acc(:, 7));
            yaw = transpose(acc(:, 11));
%             z_axis = sin(2*pi*x_acc);
%             y_axis = tan(2*pi*x_acc);
%             gps = cos(2*pi*x_acc);

            app.XALL = [
                app.norm_1_sig(movmean(z_axis(1:duration), 5)); %Z-axis - longitudinal axis 
                app.norm_1_sig(movmean(y_axis(1:duration), 5)); %Y-axis - lateral axis
                app.norm_1_sig(yaw(1:duration));    %heading angle
                app.norm_1_sig(gps(1:duration));    %Speed - speed from gps, interpolated to have the same samples as acc
                y_events(1:duration)
                ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_multi_car2(app, name)
            s = load("UCR_data\driving_dataset\driving2_ours\data_test\Scenario1_2022-01-22_11-38-19\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-38-19.txt");
%             s2 = load("UCR_data\driving_dataset\driving2_ours\data_test\Scenario1_2022-01-22_11-38-19\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-38-19.txt");
            
            acc_x = transpose(movmean(s(:, 2), 100));
            acc_y = transpose(movmean(s(:, 3), 100));
            acc_z = transpose(movmean(s(:, 4), 100));

%             gyr_x = transpose(movmean(s2(:, 2),10));
%             gyr_y = transpose(movmean(s2(:, 3), 10));
%             gyr_z = transpose(movmean(s2(1:size(s2,1), 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
                app.norm_1_sig(acc_z);
                
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car3(app, name)
            %load dataset with roundabouts
            s = load("UCR_data\driving_dataset\driving2_ours\data_test\Roundabouts_2022-01-22_11-45-42\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-45-42.txt");
            gyr = load("UCR_data\driving_dataset\driving2_ours\data_test\Roundabouts_2022-01-22_11-45-42\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-45-42.txt");
            
%             acc_x = transpose(movmean(s(1:size(gyr,1), 2),10));
            acc_y = transpose(movmean(s(1:size(gyr,1), 3), 10));
%             acc_z = transpose(movmean(s(1:size(gyr,1), 4), 10));
            gyr_z = transpose(movmean(gyr(:, 4), 10));
            gyr_x = transpose(movmean(gyr(:, 2), 10));
            gyr_y = transpose(movmean(gyr(:, 3), 10));
            
            app.XALL = [
                app.norm_1_sig(gyr_z);
                app.norm_1_sig(acc_y);
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car4(app, name)
            %load dataset with californian and normal stops

            s = load("UCR_data\driving_dataset\driving2_ours\data_test\StopVSCAStop_2022-01-22_11-47-48\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-47-48.txt");
%             gyr = load("UCR_data\driving_dataset\driving2_ours\data_test\2022-01-22_11-47-48\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-47-48.txt");
            
            acc_x = transpose(movmean(s(:, 2),50));
            acc_y = transpose(movmean(s(:, 3), 10));
%             acc_z = transpose(movmean(s(:, 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
%                 app.norm_1_sig(acc_z)
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car5(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\UCR_data\driving_dataset\driving2_ours\data_test\SpeedBumps_2022-01-22_11-52-59\opensignals_ANDROID_ACCELEROMETER_2022-01-22_11-52-59.txt");
%             s = load("UCR_data\driving_dataset\driving2_ours\data_test\2022-01-22_11-52-59\opensignals_ANDROID_GYROSCOPE_2022-01-22_11-52-59.txt");
            
            acc_x = transpose(movmean(s(:, 2),50));
            acc_y = transpose(movmean(s(:, 3), 10));
            acc_z = transpose(movmean(s(:, 4), 10));
            
            app.XALL = [
                app.norm_1_sig(acc_x);
                app.norm_1_sig(acc_y);
                app.norm_1_sig(acc_z)
            ];

            y = 0;
            X = app.XALL;            
        end

        function [y, X] = load_multi_car_TEST_Normal(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:71844, 2),10));
            acc_y = transpose(movmean(s(1:71844, 3), 10));
            acc_z = transpose(movmean(s(1:71844, 4), 10));

            gyr_z = transpose(movmean(s2(1:71844, 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;          
        end

        function [y, X] = load_multi_car_TEST_NandA(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:89587, 2),10));
            acc_y = transpose(movmean(s(1:89587, 3), 10));
            acc_z = transpose(movmean(s(1:89587, 4), 10));

            gyr_z = transpose(movmean(s2(1:89587, 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;          
        end

        function [y, X] = load_multi_car6(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-29-16.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\parking_lot_controlled_environment\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-29-16.txt");
            

            acc_x = transpose(movmean(s(1:size(s2,1), 2),10));
            acc_y = transpose(movmean(s(1:size(s2,1), 3), 10));
            acc_z = transpose(movmean(s(1:size(s2,1), 4), 10));

            gyr_z = transpose(movmean(s2(1:size(s2,1), 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_multi_car7(app, name)
            %load dataset with speed bumps
            s = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\free_road\opensignals_ANDROID_ACCELEROMETER_2022-02-12_11-56-59.txt");
            s2 = load("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\free_road\opensignals_ANDROID_GYROSCOPE_2022-02-12_11-56-59.txt");
            

            acc_x = transpose(movmean(s(1:size(s,1), 2),10));
            acc_y = transpose(movmean(s(1:size(s,1), 3), 10));
            acc_z = transpose(movmean(s(1:size(s,1), 4), 10));

            gyr_x = transpose(movmean(s2(1:size(s,1), 2), 10));
            gyr_y = transpose(movmean(s2(1:size(s,1), 3), 10));
            gyr_z = transpose(movmean(s2(1:size(s,1), 4), 10));
            
            app.XALL = [
                acc_y;
                acc_z;
                gyr_x;
                gyr_y;
                gyr_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_car_UI(app, name, ind_, road_, events_)
            if(ind_ == 6)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D6\20151217162714-26km-D6-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D6\20151221120051-26km-D6-AGGRESSIVE-MOTORWAY";
                end
            elseif(ind_ == 5)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151209151242-25km-D5-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151209153137-25km-D5-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151211164124-17km-D5-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D5\20151211162829-16km-D5-NORMAL1-SECONDARY";
                end

            elseif(ind_== 4)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151204152848-25km-D4-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151204154908-25km-D4-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151203173103-17km-D4-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D4\20151203171800-16km-D4-NORMAL1-SECONDARY";
                end

            elseif(ind_== 3)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126110502-26km-D3-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126134736-26km-D3-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126125458-16km-D3-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D3\20151126124208-16km-D3-NORMAL1-SECONDARY";
                end

            elseif(ind_== 2)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120131714-26km-D2-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120133502-26km-D2-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120162105-17km-D2-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D2\20151120160904-16km-D2-NORMAL1-SECONDARY";
                end

            elseif(ind_== 1)
                if(road_==1)
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151111123124-25km-D1-NORMAL-MOTORWAY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151111125233-24km-D1-AGGRESSIVE-MOTORWAY";
                elseif(road_==2)
                    
                    path_normal = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110180824-16km-D1-NORMAL2-SECONDARY";
                    path_aggressive = "C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\UAH-DRIVESET-v1\D1\20151110175712-16km-D1-NORMAL1-SECONDARY";
                end
            end
            s_norm = load(path_normal+"\RAW_ACCELEROMETERS.txt");
            s_aggressive = load(path_aggressive+"\RAW_ACCELEROMETERS.txt");
            
            if(events_==1)
                acc_x = transpose(s_norm(:, 6));
                acc_y = transpose(s_norm(:, 7));
                acc_z = transpose(s_norm(:, 8));
            elseif(events_==0)
                acc_x = [transpose(s_norm(:, 6)), mean(s_norm(:, 6))*ones(1, 100), transpose(s_aggressive(:, 6))];
                acc_y = [transpose(s_norm(:, 7)), mean(s_norm(:, 7))*ones(1, 100), transpose(s_aggressive(:, 7))];
                acc_z = [transpose(s_norm(:, 8)), mean(s_norm(:, 8))*ones(1, 100), transpose(s_aggressive(:, 8))];
            end

            app.XALL = [
                acc_x;
                acc_y;
                acc_z;
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_car_AUI(app, driver)
            data = readtable("C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\NLSApp\Paper1\Dataset\hcilab_driving_dataset\dataset_web\participant_"+driver+".csv");
            acc_x = transpose(movmean(data.AccelX, 250));
            acc_z = -transpose(movmean(data.AccelZ, 250));
            speed = transpose(data.Speed_GPS);

            app.XALL = [
                acc_x(1:2:end);
                acc_z(1:2:end);
                speed(1:2:end)
            ];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_segment(app)
            %Enable Channel selection
            app.Channel_EditField.Enable = "on";
            channel = app.Channel_EditField.Value;
            
            myFiles = dir(fullfile('C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\datasets_seg\datasets_seg\','*.txt'));
            files = cell(1, length(myFiles));
            for k = 1:length(myFiles)
                baseFileName = myFiles(k).name;
                fullFileName = fullfile('C:\Users\joao0\OneDrive\Documentos\PhDProjects\UCR\Data\datasets_seg\datasets_seg', baseFileName);
                fprintf(1, 'Now reading %s\n', fullFileName);
                files{k} = transpose(load(fullFileName));
            end
            app.XALL = files;
            X = app.norm_1_sig(app.XALL{channel});
            y = 0;
        end

        function [y, X] = load_multisignal_test1(app)
            s1 = zeros(1, 1000);
            s2 = zeros(1, 1000);
            
            s1(250:500) = linspace(1, 250, 251);
            s1(750:900) = linspace(1, 150, 151);
            s2(250:500) = sin(2*pi*linspace(1, 250, 251));

            app.XALL = [app.norm_1_sig(s1);
                app.norm_1_sig(s2)];

            y = 0;
            X = app.XALL;
        end

        function [y, X] = load_single_signal(app)
            s1 = 0.1*rand(1, 1000) - 0.1;
            
            s1(250:500) = linspace(0, 1, 251);
            s1(750:900) = linspace(0, 1, 151);
            
            app.XALL = s1;

            y = 0;
            X = app.XALL;
        end

        function sub_load_signals(app)
            app.loadKeywords();
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;

            app.SignalSlider.Limits = [window_size, nbr_windows-window_size];

            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 1, 'Color', 'b')

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])

            %make first update on edit field scores and subplot
            %update edit field scores
            app.updateEditFieldsScores(fix(window_size/2));

            %update plot
            app.updateSubSectionPlot(fix(window_size/2));

        end
        
         function loadKeywords(app)
            %load set of keywords with a moving window 
            win_size = app.WinSize_EditField.Value;
            %windo 1 = win_size
            
            [noise_i, up_i, down_i, flat_i, complex_i, high_i, low_i, top_i, bottom_i, speed_i, mean_i] = app.quick_moving_feature_extraction(app.SIGNAL, win_size);
            [symmetry_i, stutter_i] = app.slow_moving_feature_extraction(app.SIGNAL, win_size);
            
            [~, peak_i] = app.gauss_peak_moving(app.SIGNAL, 0.1, 2*win_size);
            [~, valley_i] = app.gauss_val_moving(app.SIGNAL, 0.1, 2*win_size);
            
            vval_i = app.v_gauss_val_moving(app.SIGNAL, 2*win_size);
            uval_i = app.u_gauss_val_moving(app.SIGNAL, 2*win_size);
           
            [pos_step_i, neg_step_i] = app.moving_step(app.SIGNAL, 2*win_size);
            [pos_plat_i, neg_plat_i] = app.moving_plateau(app.SIGNAL, 2*win_size);
%             [uncommon_i, common_i] = app.MP([fliplr(app.SIGNAL(1:win_size/2)), app.SIGNAL, fliplr(app.SIGNAL(end-win_size/2:end))], win_size);
            
            %window 2 = win_size/4
            [noise_i2, up_i2, down_i2, flat_i2, complex_i2, high_i2, low_i2, top_i2, bottom_i2, speed_i2, mean_i2] = app.quick_moving_feature_extraction(app.SIGNAL, fix(win_size/2));
            [symmetry_i2, stutter_i2] = app.slow_moving_feature_extraction(app.SIGNAL, win_size/2);
            
            [~, peak_i2] = app.gauss_peak_moving(app.SIGNAL, 0.1, win_size);
            [~, valley_i2] = app.gauss_val_moving(app.SIGNAL, 0.1, win_size);
            vval_i2 = app.v_gauss_val_moving(app.SIGNAL, win_size);
            uval_i2 = app.u_gauss_val_moving(app.SIGNAL, win_size);
            [pos_step_i2, neg_step_i2] = app.moving_step(app.SIGNAL, win_size);
            [pos_plat_i2, neg_plat_i2] = app.moving_plateau(app.SIGNAL, win_size);
%             [uncommon_i2, common_i2] = app.MP([fliplr(app.SIGNAL(1:win_size/2)), app.SIGNAL, fliplr(app.SIGNAL(end-win_size/2:end))], win_size/4);
            
            %window 2 = win_size/16
            if(win_size/16<2)
                win_size_ = 32;
            else
                win_size_ = win_size;
            end
            
            [noise_i3, up_i3, down_i3, flat_i3, complex_i3, high_i3, low_i3, top_i3, bottom_i3, speed_i3, mean_i3] = app.quick_moving_feature_extraction(app.SIGNAL, fix(win_size_/4));
            [symmetry_i3, stutter_i3] = app.slow_moving_feature_extraction(app.SIGNAL, fix(win_size_/4));
            [~, peak_i3] = app.gauss_peak_moving(app.SIGNAL, 0.1, fix(win_size_/2));
            [~, valley_i3] = app.gauss_val_moving(app.SIGNAL, 0.1, fix(win_size_/2));
            vval_i3 = app.v_gauss_val_moving(app.SIGNAL, fix(win_size/2));
            uval_i3 = app.u_gauss_val_moving(app.SIGNAL, fix(win_size/2));
            [pos_step_i3, neg_step_i3] = app.moving_step(app.SIGNAL, fix(win_size_/2));
            [pos_plat_i3, neg_plat_i3] = app.moving_plateau(app.SIGNAL, fix(win_size_/2));
%             [uncommon_i3, common_i3] = app.MP([fliplr(app.SIGNAL(1:win_size/2)), app.SIGNAL, fliplr(app.SIGNAL(end-win_size/2:end))], win_size/16);

            %map keywords and keys into a container, already normalized
            keywords_keys = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'high', 'low', 'peak', 'valley', 'stepup', 'stepdown', 'plateauup', 'plateaudown', 'top', 'bottom', 'stutter', 'eventful', 'vval', 'uval', 'middle'};
            keywords_vals1 = {noise_i, up_i, down_i, flat_i, symmetry_i, complex_i, high_i, low_i, peak_i, valley_i, pos_step_i, neg_step_i, pos_plat_i, neg_plat_i,top_i, bottom_i, stutter_i, speed_i, vval_i, uval_i, mean_i};
            keywords_vals2 = {noise_i2, up_i2, down_i2, flat_i2, symmetry_i2, complex_i2, high_i2, low_i2, peak_i2, valley_i2, pos_step_i2, neg_step_i2, pos_plat_i2, neg_plat_i2, top_i2, bottom_i2, stutter_i2, speed_i2, vval_i2, uval_i2, mean_i2};
            keywords_vals3 = {noise_i3, up_i3, down_i3, flat_i3, symmetry_i3, complex_i3, high_i3, low_i3, peak_i3, valley_i3, pos_step_i3, neg_step_i3, pos_plat_i3, neg_plat_i3, top_i3, bottom_i3, stutter_i3, speed_i3, vval_i3, uval_i3, mean_i3};

            app.KEY_VECTOR1 = containers.Map(keywords_keys, keywords_vals1);
            app.KEY_VECTOR2 = containers.Map(keywords_keys, keywords_vals2);
            app.KEY_VECTOR3 = containers.Map(keywords_keys, keywords_vals3);
         end

        %------------------------------------------------------------------
        % Multi Dimensional methods
        %------------------------------------------------------------------

        function sub_load_signals_multi(app)
            app.load_multi_Keywords();
            %update slider value based on size of the signal
            nbr_windows = size(app.SIGNAL, 2);
            window_size = app.WinSize_EditField.Value;

            app.SignalSlider.Limits = [window_size, nbr_windows-window_size];

            hold(app.UIAxes, 'off');
            
            for i = 1:size(app.SIGNAL, 1)
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1)
                hold(app.UIAxes, 'on')
            end

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL,1)])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, size(app.SIGNAL, 2)+rectWidth])

%             make first update on edit field scores and subplot
%             update edit field scores
            app.updateEditFieldsScores_multi(fix(window_size/2));

%             update plot
            app.updateSubSectionPlot_multi(fix(window_size/2));
        end
        
        function load_multi_Keywords(app)
            %load set of keywords with a moving window
            win_size = app.WinSize_EditField.Value;
            %Reshape signal into 1d signal for more efficient
            %moving_extraction
            len_signal = size(app.XALL, 2);
            nbr_signals = size(app.XALL, 1);
            keywords_keys = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'high', 'low', 'peak', 'valley', 'stepup', 'stepdown', 'plateauup', 'plateaudown','top','bottom', 'stutter', 'eventful', 'vval', 'uval', 'middle'};
            %mirror each signal, so that border issues are not an issue
            keywords_vals1 = cell(1, length(keywords_keys));
            keywords_vals2 = cell(1, length(keywords_keys));
            keywords_vals3 = cell(1, length(keywords_keys));
            d1 = cell(nbr_signals, length(keywords_keys));
            d2 = cell(nbr_signals, length(keywords_keys));
            d3 = cell(nbr_signals, length(keywords_keys));
            tic;
            for i = 1:nbr_signals
                %sig
                s = app.XALL(i,:);
                
                %windo 1 = win_size
                [d1{i,1}, d1{i,2}, d1{i,3}, d1{i,4}, d1{i,6}, d1{i, 7}, d1{i, 8}, d1{i, 15}, d1{i, 16}, d1{i,18}, d1{i, 21}] = app.quick_moving_feature_extraction(s, win_size);
                [d1{i,5}, d1{i,17}] = app.slow_moving_feature_extraction(s, win_size);

                [~, d1{i, 9}] = app.gauss_peak_moving(s, 0.1, 2*win_size);
                [~, d1{i, 10}] = app.gauss_val_moving(s, 0.1, 2*win_size);
                
                [d1{i,11}, d1{i,12}] = app.moving_step(s, win_size*2);
                [d1{i,13}, d1{i,14}] = app.moving_plateau(s, win_size*2);

                d1{i,19} = app.v_gauss_val_moving(s, 2*win_size);
                d1{i,20} = app.u_gauss_val_moving(s, 2*win_size);

                %window 2 = win_size/4
                [d2{i,1}, d2{i,2}, d2{i,3}, d2{i,4}, d2{i,6}, d2{i, 7}, d2{i, 8}, d2{i, 15}, d2{i, 16}, d2{i,18}, d2{i,21}] = app.quick_moving_feature_extraction(s, fix(win_size/4));
                [d2{i,5}, d2{i,17}] = app.slow_moving_feature_extraction(s, fix(win_size/4));

                [~, d2{i, 9}] = app.gauss_peak_moving(s, 0.1, win_size);
                [~, d2{i, 10}] = app.gauss_val_moving(s, 0.1, win_size);
                
                [d2{i,11}, d2{i,12}] = app.moving_step(s, win_size);
                [d2{i,13}, d2{i,14}] = app.moving_plateau(s, win_size);

                d2{i,19} = app.v_gauss_val_moving(s, win_size);
                d2{i,20} = app.u_gauss_val_moving(s, win_size);

                %window 2 = win_size/16
                [d3{i,1}, d3{i,2}, d3{i,3}, d3{i,4}, d3{i,6}, d3{i, 7}, d3{i, 8}, d3{i, 15}, d3{i, 16}, d3{i,18}, d3{i, 21}] = app.quick_moving_feature_extraction(s, fix(win_size/16));
                [d3{i,5}, d3{i,17}] = app.slow_moving_feature_extraction(s, fix(win_size/16));

                [~, d3{i, 9}] = app.gauss_peak_moving(s, 0.1, fix(win_size/4));
                [~, d3{i, 10}] = app.gauss_val_moving(s, 0.1, fix(win_size/4));

                [d3{i,11}, d3{i,12}] = app.moving_step(s, fix(win_size/4));
                [d3{i,13}, d3{i,14}] = app.moving_plateau(s, fix(win_size/4));

                d3{i,19} = app.v_gauss_val_moving(s, fix(win_size/4));
                d3{i,20} = app.u_gauss_val_moving(s, fix(win_size/4));
            end
            toc;
            
            for i = 1:length(keywords_keys)
                keywords_vals1{i} = cell2mat(d1(:,i));
                keywords_vals2{i} = cell2mat(d2(:,i));
                keywords_vals3{i} = cell2mat(d3(:,i));
            end

            %map keywords and keys into a container, already normalized
            app.KEY_VECTOR1 = containers.Map(keywords_keys, keywords_vals1);
            app.KEY_VECTOR2 = containers.Map(keywords_keys, keywords_vals2);
            app.KEY_VECTOR3 = containers.Map(keywords_keys, keywords_vals3);
        end

        function vals = reshape_back(~, cell_arr, len_signal, nbr_signals)
            vals = {};
            for i = 1:length(cell_arr)
                cell_i = cell_arr{1,i};
                reshaped_arr = reshape(cell_i, [nbr_signals, len_signal]);
                vals{i} = reshaped_arr;
            end
        end

        function updateEditFieldsScores_multi(app, changingValue)

            %get ordered values:
            keywords = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'peak', 'valley'};
            key_scores = [];
            for key_i = keywords
                keyword_i = app.KEY_VECTOR1(char(key_i));
                %for first signal
                key_score_1 = keyword_i(1, :);
                key_scores = [key_scores key_score_1(changingValue)];
            end

            %Noise Section
            app.NoiseEditField.Value = key_scores(1);

            %Slope Section
            app.UpEditField.Value  = key_scores(2);
            app.DownEditField.Value = key_scores(3);

            %Flat Section
            app.FlatEditField.Value = key_scores(4);
            
            %Complexity Section
            app.ComplexityEditField.Value = key_scores(6);
            
            %Symmetry Section
            app.SymmetryEditField.Value = key_scores(5);

            %Peak Section
            app.PeakEditField.Value = key_scores(7);

            %Valley Section
            app.ValleyEditField.Value = key_scores(8);
        end

        function updateSubSectionPlot_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            %plot first signal
            section = app.SIGNAL(1, a:b);
            plot(app.UIAxes2, section)
        end


        %------------------------------------------------------------------
        % Normalization methods
        %------------------------------------------------------------------

        function norm_sig = norm_1_sig(~, s)
            if(max(s)==min(s))
                norm_sig = zeros(1, length(s));
            else
                norm_sig = (s - min(s))/(max(s)-min(s));
            end
        end

        function norm_sig = norm_1_sig_with_ref(~, s, min_, max_)
            norm_sig = (s - min_)/(max_ - min_);
        end

        %------------------------------------------------------------------
        % Keyword extraction methods
        %------------------------------------------------------------------

        function residual_sig = wbr(~, sig, win_size)
        %noise level, based on removing the baseline and getting the residuals
            filt_sig = movmean(sig, win_size);
            residual_sig = sig - filt_sig;
        end

        function residuals = cum_wbr(app, sig, win)
        %check how noisy is a signal by subtracting the wandering baseline
        %to the signal and summing the residuals values (need to check the
        %win value)
            res_sig = app.wbr(sig, win);
            residuals = sum(abs(res_sig));
        end

        function [up, down] = slope_estimation(~, sig)
        %Estimate the slope of a linear approximation to the signal.
        %Basically return m, from y = mx + b
            P = polyfit(linspace(0, length(sig), length(sig)), sig, 1);
            slope_ = P(1);

            if(slope_>0)
                up = slope_;
                down = 0;
            elseif(slope_<0)
                up = 0;
                down = abs(slope_);
            else
                up = 0;
                down = 0;
            end
        end
        
        function symmetry = symmetry_estimation(~, sig2search, query)
        %Search minimum of left matrix profile over the fliped signal.
        %The lower the value, the higher is the symmetry.
            mass_distance = MASS_V2(sig2search, query);
            if(isnan(min(mass_distance)))
                symmetry = 0;
            else
                symmetry = real(min(mass_distance));
            end
        end

        function complexity = complexity_estimation(~, sig)
        %Search how complex a signal is by estimating the absolute distance traveled
        %by the signal
            complexity = sqrt(sum(diff(sig).^2));
        end

        function peak_measure = detect_peaks(~, s)
            [pks, lcs] = findpeaks(s, "MinPeakProminence", 0.1);
            peak_measure = length(pks);
        end

        function throttle = stutter_estimation(~, sig, query)
            sig2search = [fliplr(sig(1:fix(length(sig)/10)+1)) sig fliplr(sig(fix(length(sig)/10)+1:end))];
            mass_distance = MASS_V2(sig2search, query);

            if(isnan(min(mass_distance)))
                throttle = 0;
            else
                throttle = real(min(mass_distance));
            end
%             throttle = norm(sig - query);
        end

        function [uncommon, common] = MP(app, s, win_size)
            if(win_size<4)
                win_size = 4;
            end
            [uncommon, ~] = mstamp(transpose(s), fix(win_size), [], []);
            uncommon = app.norm_1_sig(transpose(uncommon(1:length(s)-win_size-1)));
            common = 1 - uncommon;
        end

        %------------------------------------------------------------------
        % Sliding Method to retrieve moving keywords
        %------------------------------------------------------------------
        function [moving_noise, moving_up, moving_down, moving_flat, moving_complexity, moving_high, moving_low, moving_top, moving_bottom, moving_speed, moving_middle] = quick_moving_feature_extraction(app, sig, win_size)
            
            half_win_size = fix(win_size/2);

            mirrored_sig = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size+1:end))];
            mirror_sig_mat = buffer(mirrored_sig, win_size, win_size-1, 'nodelay');
            mirror_sig_mat = mirror_sig_mat(:, 1:length(sig));

            moving_noise = app.norm_1_sig(movstd(sig, win_size));

            moving_complexity = app.norm_1_sig(sqrt(movsum(diff(sig).^2, win_size)));
            moving_complexity = [moving_complexity, moving_complexity(end)];
            
            moving_high = app.norm_1_sig(movmax(sig, win_size) - movmin(sig, win_size));
            moving_low = 1-moving_high;
            
            moving_top = app.norm_1_sig(movmean(sig, win_size));
%             moving_middle = 1 - app.norm_1_sig(abs(sig - movmean(sig, win_size)));
            moving_middle = 1 - app.norm_1_sig(abs(movmean(sig-mean(sig), win_size)));
            moving_bottom = 1-moving_top;

            moving_speed = app.norm_1_sig(movsum(abs(diff(sig)), win_size));
            moving_speed = [moving_speed, moving_speed(end)];

            x_mat = [ones(win_size, 1), transpose(linspace(1, win_size, win_size))];
            slopes_ = x_mat\mirror_sig_mat;
            slopes_ = slopes_(2, :);

            moving_up = app.norm_1_sig(max(slopes_, 0));
            moving_down = app.norm_1_sig(abs(min(slopes_, 0)));

%             moving_flat = 1-app.norm_1_sig(abs(slopes_));
%             moving_flat = 1 - app.norm_1_sig(movsum(abs(sig), win_size) - movmean(sig, win_size));
            moving_flat = 1 - app.norm_1_sig(sum(abs(mirror_sig_mat - mean(mirror_sig_mat, 1)), 1));

        end

        function [moving_symmetry, moving_stutter] = slow_moving_feature_extraction(app, sig, win_size)

        %Moving window keywords extraction method. It returns a signal for
        %each keyword with the same size of the original signal, because it
        %mirrors the original signal with half the size of the defined
        %window. This process also prevents border issues.
            
            half_win_size = fix(win_size/2);
            mirrored_sig = [fliplr(sig(1:win_size)), sig, fliplr(sig(end-win_size-half_win_size:end))];
            
            moving_symmetry = zeros(1, length(sig));
            moving_stutter = zeros(1, length(sig));
            
%             for i = win_size:length(sig)+win_size-1
%                 sec_mirrored_sig = mirrored_sig(i-half_win_size+1:i+half_win_size);
%                 sig2search_symmetry = mirrored_sig(i-win_size+1:i+win_size);
%                 %extract features
%                 %Symmetry--------------------------------------------------------------------------
%                 moving_symmetry(i-win_size+1) = app.symmetry_estimation(sig2search_symmetry, fliplr(sec_mirrored_sig));
%                 %Up, Down and Flat-----------------------------------------------------------------
%             
% %                 section of the signal that will be used to compare with
% %                 the previous section and understand if a pattern is
% %                 repeated in time. If so, 
%                 sec_prev_window = mirrored_sig(i-win_size+1:i);
%                 sec_current_window = mirrored_sig(i-fix(win_size/4):i+win_size+fix(win_size/4));
%                 moving_stutter(i-win_size+1) = app.stutter_estimation(sec_current_window, sec_prev_window);
%             end            
%             
%             
%             %invert symmetry to correctly assign value association
%             moving_symmetry = 1 - app.norm_1_sig(moving_symmetry);
% 
%             %Is the next window very similar in shape with the current one?
%             moving_stutter = 1 - app.norm_1_sig(moving_stutter);
        end

        function [query_peak, mass_peak] = gauss_peak_moving(app, sig, c, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            query_peak = a*exp(((x-b).^2)/(-2*(c^2)));
            mass_peak = 1 - app.norm_1_sig(log(MASS_V2(sig_, query_peak)));
            mass_peak = mass_peak(1:length(sig));
        end
        
        function [valley_, mass_valley] = gauss_val_moving(app, sig, c, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(c^2)));
            mass_valley = 1 - app.norm_1_sig(log(MASS_V2(sig_, valley_)));
            mass_valley = mass_valley(1:length(sig));
        end

        function mass_v_valley = v_gauss_val_moving(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];
            a = 1;
            b = 0;
            
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(0.2^2)));
            
            mass_v_valley = 1 - app.norm_1_sig(log(MASS_V2(sig_, valley_)));
            mass_v_valley = mass_v_valley(1:length(sig));
        end

        function mass_u_valley = u_gauss_val_moving(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];
            a = 1;
            b = 0;
            x = linspace(-1, 1, win_size);
            valley_ = -a*exp(((x-b).^2)/(-2*(0.5^2)));
            mass_u_valley = 1 - app.norm_1_sig(log(MASS_V2(sig_, valley_)));
            mass_u_valley = mass_u_valley(1:length(sig));
        end

        function [pos_step, neg_step] = moving_step(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];

            pos_step_query = zeros(1, win_size);
            neg_step_query = zeros(1, win_size);

            pos_step_query(win_size/2:end) = 1;
            neg_step_query(win_size/2:end) = -1;

            pos_step = MASS_V2(sig_, pos_step_query);
            pos_step = 1 - app.norm_1_sig(pos_step(1:length(sig)));
            neg_step = MASS_V2(sig_, neg_step_query);
            neg_step = 1 - app.norm_1_sig(neg_step(1:length(sig)));
        end
        
        function [pos_plateau, neg_plateau] = moving_plateau(app, sig, win_size)
            half_win_size = fix(win_size/2);
            sig_ = [fliplr(sig(1:half_win_size)), sig, fliplr(sig(end-half_win_size:end))];

            pos_plat_query = zeros(1, win_size);
            neg_plat_query = zeros(1, win_size);

            pos_plat_query(win_size/4:3*win_size/4) = 1;
            neg_plat_query(win_size/4:3*win_size/4) = -1;

            pos_plateau = MASS_V2(sig_, pos_plat_query);
            pos_plateau = 1 - app.norm_1_sig(pos_plateau(1:length(sig)));
            neg_plateau = MASS_V2(sig_, neg_plat_query);
            neg_plateau = 1 - app.norm_1_sig(neg_plateau(1:length(sig)));
        end
        

        %------------------------------------------------------------------
        % Sliding Update methods
        %------------------------------------------------------------------
        function updateEditFieldsScores(app, changingValue)

            %get ordered values:
            keywords = {'noise', 'up', 'down', 'flat', 'symmetric', 'complex', 'peak', 'valley'};
            key_scores = [];
            for key_i = keywords
                keyword_i = app.KEY_VECTOR1(char(key_i));
                key_scores = [key_scores keyword_i(changingValue)];
            end

            %Noise Section
            app.NoiseEditField.Value = key_scores(1);

            %Slope Section
            app.UpEditField.Value  = key_scores(2);
            app.DownEditField.Value = key_scores(3);

            %Flat Section
            app.FlatEditField.Value = key_scores(4);
            
            %Complexity Section
            app.ComplexityEditField.Value = key_scores(6);
            
            %Symmetry Section
            app.SymmetryEditField.Value = key_scores(5);

            %Peak Section
            app.PeakEditField.Value = key_scores(7);

            %Valley Section
            app.ValleyEditField.Value = key_scores(8);
        end

        function recreatePatchwindow(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, length(app.SIGNAL)+win_size])
            
        end
        
        function updateSubSectionPlot(app, changingValue)
            win_size = app.WinSize_EditField.Value;

            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            rectY = ylim(app.UIAxes);
            app.PATCH.XData = rectX([1,2,2,1]);
            app.PATCH.YData = rectY([1,1,2,2]);
            a = changingValue-fix(win_size/2)+1;
            b = a + win_size;
            if(size(app.SIGNAL,1)>1)
                section = app.SIGNAL(1, a:b);
            else
                section = app.SIGNAL(a:b);
            end
            plot(app.UIAxes2, section)
        end

        %------------------------------------------------------------------
        % Query Score methods
        %------------------------------------------------------------------

        function scores = extract_keywords_score(app, keywords)
            
            if(length(keywords)<3)
                key_vector = app.KEY_VECTOR2;
            elseif length(keywords)>2
                key_vector = app.KEY_VECTOR3;
            end
            
            win_size = app.WinSize_EditField.Value;
            win_sub_size = fix(win_size/(length(keywords)));
            
            scores = zeros(length(keywords), length(app.SIGNAL)-win_size);
            
            for i = 1:length(keywords)
                %check for operators on keyword
                if(contains(keywords(i), '!'))
                    keyword = lower(char(keywords(i)));
                    keyword = keyword(2:end);
                    search_op = 1;
                else
                    keyword = lower(char(keywords(i)));
                    search_op = 2;
                end

                a = i*win_sub_size;
                b = (length(app.SIGNAL)-win_size)+(i*win_sub_size) - 1;
                
                %Check which keyword is written
                if(keyword==".")
                    param_vec = zeros(1, b-a+1);
                else
                    data_i = key_vector(keyword);
                    param_vec = movmean(data_i(a:b), fix(win_sub_size/2));
                end
                
                if(search_op == 1)
                    scores(i, :) = 1 - param_vec;
                elseif(search_op == 2)
                    scores(i, :) = param_vec;
                end

            end
            scores = app.norm_1_sig(sum(scores, 1));
        end

        function norm_mean_scores = windowed_query_scores(app, window_query)
            keywords = split(window_query);
            
            %extract keywords for each signal, subwindowed and normalized
            norm_mean_scores = app.extract_keywords_score(keywords);
        end

        function single_keyword_score = single_keyword_score_estimation(app, keyword)
            %check for operators on keyword
            if(contains(keyword, '!'))
                keyword = lower(char(keyword));
                keyword = keyword(2:end);
                single_keyword_score = 1 - app.KEY_VECTOR1(keyword);
            else
                keyword = lower(char(keyword));
                single_keyword_score = app.KEY_VECTOR1(keyword);
            end
            win_size = app.WinSize_EditField.Value;
            a = fix(win_size/2);
            single_keyword_score = single_keyword_score(a:end-a-1);
        end

        function scores = uni_query_search(app, query_group)
           %keywords
            [bracket_groups, keywords_groups] = regexp(query_group, "\[.+?\]", "match", "split");
            win_size = app.WinSize_EditField.Value;
            scores = zeros(1, length(app.SIGNAL)-win_size);
            %2 - calculate scores for each bracket group and single keyword
            %2.1 - start with brackets
            for bracket_i = bracket_groups
                %convert 2 char
                bracket_content = char(bracket_i);
                %calculate score and add to the previous array
                scores = scores + app.windowed_query_scores(bracket_content(2:end-1));
            end
            
            %2.2 - calculate scores for each singular keyword
            merged_groups = strjoin(keywords_groups);
            splitted_keywords = split(merged_groups, " ");
            for i = 1:length(splitted_keywords)
                if(size(char(splitted_keywords(i)),2)==0)
                    continue
                else
                    keyword_score = app.single_keyword_score_estimation(splitted_keywords(i));
                    scores = scores + keyword_score;
                end
            end
            
        end

        function scores = followed_by_search(app, query)
            win_size = app.WinSize_EditField.Value;
            
            queries = split(query, "followed by");
            query1 = queries(1);
            query2 = queries(2);
            
            score1 = app.uni_query_search(char(query1));
            score2 = app.uni_query_search(char(query2));
            scores = zeros(1, length(score1));
            scores(1:length(score1)-win_size) = score1(1:length(score1)-win_size) + score2(win_size+1:end);
        end

        %------------------------------------------------------------------
        % Query Score methods with multiple signals
        %------------------------------------------------------------------

        function scores_sig_i = multi_query_search(app, query_groups, signal_indxs)
            win_size = app.WinSize_EditField.Value;
            scores_sig_i = zeros(1, size(app.SIGNAL, 2)-win_size);
            for i = 1:length(query_groups)-1
                %get the index of the signal you will search the query in
                signal_i = str2num(char(regexp(char(signal_indxs(i)), "\d", 'match')));
                %search the query on the previous signal's index
                scores_sig_i = scores_sig_i + app.norm_1_sig(app.multi_uni_query_search(char(query_groups(i+1)), signal_i));
            end
        end

        function scores = multi_query_search_followedby(app, query_groups, signal_indxs)
            win_size = app.WinSize_EditField.Value;
            scores = zeros(1, size(app.SIGNAL, 2)-win_size);
            
            signal1 = str2num(char(regexp(char(signal_indxs(1)), "\d", 'match')));
            score1 = app.norm_1_sig(app.multi_uni_query_search(char(query_groups(1)), signal1));

            signal2 = str2num(char(regexp(char(signal_indxs(2)), "\d", 'match')));
            score2 = app.norm_1_sig(app.multi_uni_query_search(char(query_groups(2)), signal2));
            
            scores(1:length(score1)-win_size) = score1(1:length(score1)-win_size) + score2(win_size+1:end);
        end

        function scores_sig_i = multi_uni_query_search(app, query, index_i)
            %2 - calculate scores for each bracket group and single keyword
            %2.1 - start with brackets
            %keywords
            [bracket_groups, keywords_groups] = regexp(query, "\[.+?\]", "match", "split");
            win_size = app.WinSize_EditField.Value;
            scores = zeros(1, size(app.SIGNAL, 2)-win_size);
            
            for bracket_i = bracket_groups
                %convert 2 char
                bracket_content = char(bracket_i);
                %calculate score and add to the previous array
                scores = scores + app.windowed_query_scores_multi(bracket_content(2:end-1), index_i);
            end
            
            %2.2 - calculate scores for each singular keyword
            merged_groups = strjoin(keywords_groups);
            splitted_keywords = split(merged_groups, " ");
            for i = 1:length(splitted_keywords)
                if(size(char(splitted_keywords(i)),2)==0)
                    continue
                else
                    keyword_score = app.single_keyword_score_estimation_multi(splitted_keywords(i), index_i);
                    scores = scores + keyword_score;
                end
            end
            
            %the score function for that specific signal (index_i)
            scores_sig_i = scores;
        end

        function norm_mean_scores = windowed_query_scores_multi(app, window_query, index_i)
            keywords = split(window_query);
            
            %extract keywords for each signal, subwindowed and normalized
            norm_mean_scores = app.extract_keywords_score_multi(keywords, index_i);
        end

        function brackets_scores = extract_keywords_score_multi(app, keywords, index_i)
            
            if(length(keywords)<3)
                key_vector = app.KEY_VECTOR2;
            elseif length(keywords)>2
                key_vector = app.KEY_VECTOR3;
            end
            
            win_size = app.WinSize_EditField.Value;
            win_sub_size = fix(win_size/length(keywords));
            
            scores = zeros(length(keywords), size(app.SIGNAL, 2)-win_size);
            
            for i = 1:length(keywords)
                %check for operators on keyword
                if(contains(keywords(i), '!'))
                    keyword = lower(char(keywords(i)));
                    keyword = keyword(2:end);
                    search_op = 1;
                else
                    keyword = lower(char(keywords(i)));
                    search_op = 2;
                end

                a = (i-1)*win_sub_size+1;
                b = (size(app.SIGNAL, 2)-win_size)+((i-1)*win_sub_size);
                
                %get vector of values associated with the keyword written
                %for a specific window
                %Added a movmean to each keyword when used, based on the
                %win_sub_size.
                if(keyword==".")
                    param_vec = zeros(1, b-a+1);
                else
                    data_i = key_vector(keyword);
                    data_i = movmean(data_i(index_i, :), fix(win_sub_size/2));
                    param_vec = data_i(a:b);
                end
                
                if(search_op == 1)
                    scores(i, :) = 1 - param_vec;
                elseif(search_op == 2)
                    scores(i, :) = param_vec;
                end

            end
            brackets_scores = app.norm_1_sig(sum(scores, 1));
        end

        function single_keyword_score = single_keyword_score_estimation_multi(app, keyword, index_i)
            %check for operators on keyword
            if(contains(keyword, '!'))
                keyword = lower(char(keyword));
                keyword = keyword(2:end);
                key_vector = app.KEY_VECTOR1(keyword);
                single_keyword_score = 1 - key_vector(index_i, :);
            else
                keyword = lower(char(keyword));
                key_vector = app.KEY_VECTOR1(keyword);
                single_keyword_score = key_vector(index_i, :);
            end

            win_size = app.WinSize_EditField.Value;
            a = fix(win_size/2);
            single_keyword_score = single_keyword_score(a:end-a-1);
        end

        function recreatePatchwindow_multi(app, changingValue)
            win_size = app.WinSize_EditField.Value;
            
            rectX = (changingValue-fix(win_size/2)+1) + win_size*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5*size(app.SIGNAL,1)])
            rectY = ylim(app.UIAxes);

            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-win_size, size(app.SIGNAL, 2)+win_size])
        end

        %------------------------------------------------------------------
        % Multi and Single Level Search Methods
        %------------------------------------------------------------------

        function [scores, signal_groups] = single_level_search(app, query)
            % 2 - check if single, or multiple signal
            [sig_indexs, signal_groups] = regexp(query, "s\d:","match", "split");
            
            % 3 - apply regex to query, separating brackets and single, now
            %also taking care of multisignals
            
            %If there are multiple signals being queried
            if(length(signal_groups)>1)
                if(contains(signal_groups(2), "followed by"))
                    %If there is a "followed by" statement for multiple
                    %signals, a different approach should be made
                    group1 = split(signal_groups(2), "followed by");
                    group2 = signal_groups(3);
                    scores = app.norm_1_sig(app.multi_query_search_followedby([group1(1), group2], sig_indexs));
                %If normal interaction between signals, than it is the
                %normal addition of scores
                else
                    scores = app.multi_query_search(signal_groups, sig_indexs);
                end
            else
                % 1 - Check the presence of sequence operator
                [followed_by_match, remaining_individual_queries] = regexp(query, "\w+ followed by \w+","match", "split");
                %check scores for followed by
                if(size(followed_by_match, 1)>0)
                    score_fb = app.norm_1_sig(app.followed_by_search(followed_by_match));
                    query = strjoin(remaining_individual_queries);
                else
                    query = signal_groups;
                end
                
                scores = app.uni_query_search(char(query));
                scores = scores + score_fb;
            end

            scores = app.norm_1_sig(scores);

        end

        function [new_scores, signal_groups] = multi_level_search(app, query)
            %level 1
            [scores, signal_groups] = app.single_level_search(query(1, :));

            %level 2
            [scores2, ~] = app.single_level_search(query(2, :));


            %find max
            win_size = app.WinSize_EditField.Value;
            half_win = fix(win_size/2);
            
            k = app.K_events_EditField.Value;
            arg_k = zeros(1, k);
            new_scores = zeros(1, length(scores));
            for k_i = 1:k
                [~, argmax_ki] = max(scores);
                %save index to plot around it
                
                arg_k(k_i) = fix(win_size/2) + argmax_ki;
                %remove the score and its surrounding 
                a = argmax_ki-half_win+1;
                b = argmax_ki+half_win;
                
                new_scores(a:b) = scores2(a:b);

                scores(a:b) = 0;
            end

            new_scores = app.norm_1_sig(new_scores);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadSignal(app, event)
        %This function initializes the entire process by (1) loading the
        %signal and (2) pre-loading the signal representation through each
        %keyword based on the window size selected

            %Get the name of the dataset to load the data correctly
            dataset_name = app.load_EditField.Value;

            %Function to load the dataset based on the name given if UCR is
            %contained
            if(contains(dataset_name, "ucr"))
                [y, X] = app.loadUCR(dataset_name);
            elseif(contains(dataset_name, 'physio'))
                [y, X] = app.loadPhysio(dataset_name);
            elseif(contains(dataset_name, 'car'))
                [y, X] = app.loadmultiCar(dataset_name);
            elseif(contains(dataset_name, 'driving1'))
                [y, X] = app.load_multi_car2(dataset_name);
            elseif(contains(dataset_name, 'driving2'))
                [y, X] = app.load_multi_car3(dataset_name);
            elseif(contains(dataset_name, 'driving3'))
                [y, X] = app.load_multi_car4(dataset_name);
            elseif(contains(dataset_name, 'driving4'))
                [y, X] = app.load_multi_car5(dataset_name);
            elseif(contains(dataset_name, 'driving5'))
                [y, X] = app.load_multi_car6(dataset_name);
            elseif(contains(dataset_name, 'driving_ours_normal'))
                [y, X] = app.load_multi_car_TEST_Normal(dataset_name);
            elseif(contains(dataset_name, 'driving_ours_aggressive'))
                [y, X] = app.load_multi_car_TEST_NandA(dataset_name);
            elseif(contains(dataset_name, 'driving6'))
                [y, X] = app.load_multi_car7(dataset_name);
            elseif(contains(dataset_name, 'drive_AvsN_1'))
                [y, X] = app.load_car_UI(dataset_name, 1, 1, 0);   
            elseif(contains(dataset_name, 'drive_AvsN_2'))
                [y, X] = app.load_car_UI(dataset_name, 2, 1, 0);
            elseif(contains(dataset_name, 'drive_AvsN_3'))
                [y, X] = app.load_car_UI(dataset_name, 3, 1, 0);   
            elseif(contains(dataset_name, 'drive_AvsN_4'))
                [y, X] = app.load_car_UI(dataset_name, 4, 1, 0);
            elseif(contains(dataset_name, 'drive_AvsN_5'))
                [y, X] = app.load_car_UI(dataset_name, 5, 1, 0);
            elseif(contains(dataset_name, 'drive_AvsN_6'))
                [y, X] = app.load_car_UI(dataset_name, 6, 1, 0); 
            elseif(contains(dataset_name, 'drive_AUI'))
                driver = split(dataset_name, '_');
                driver = driver(end);
                [y, X] = app.load_car_AUI(driver); 
            elseif(contains(dataset_name, 'multi_test'))
                [y, X] = app.load_multisignal_test1();
            elseif(contains(dataset_name, 'segment'))
                [y, X] = app.load_segment();
            elseif(contains(dataset_name, 'bird'))
                [y, X] = app.loadBird('bird');
            elseif(contains(dataset_name, '1D_test'))
                [y, X] = app.load_single_signal();
            else
                [y, X] = app.loadSig(dataset_name);
            end
            
            %In this case, there are no classes...
            colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
            app.COLORS = colors;  
            %we have signal now!
            app.ITER = 1;
            app.SIGNAL = X;
            %load keyword representation of the signal extracted in
            %multiple dimensions
            if(size(X, 1) > 1)
                app.sub_load_signals_multi();
            else
                app.sub_load_signals();
            end

        end

        % Value changing function: SignalSlider
        function SignalSliderValueChanging(app, event)
            %Get value from slider
            changingValue = fix(event.Value);
            
            if(changingValue+1>(length(app.SIGNAL)-app.WinSize_EditField.Value/2))
                changingValue = fix(length(app.SIGNAL)-app.WinSize_EditField.Value/2)-1;
            end
            
            if(size(app.SIGNAL, 1) > 1)
                app.updateEditFieldsScores_multi(changingValue);    
            else
                app.updateEditFieldsScores(changingValue);    
            end
            %update edit field scores
            

            %update plot
            app.updateSubSectionPlot(changingValue);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %Parse text----------------------------------------------
            %   Idea 2 - Check if keywords with space or brackets
            %   indicating window -> Now using regex!!!
            %   Idea 3 (Now in version 4 of the app) - include followed
            %   by/next to/after/before (for now...only using 1 time and
            %   not multiple instances of that operator)
            %--------------------------------------------------------
            tic
            query = char(app.Query_EditField.Value);
            win_size = app.WinSize_EditField.Value;
            
            % 1 - check if multi_part search or single part search:
            search_levels = size(query, 1);
            
            if(search_levels>1)
                %do multilevel search
                [scores, signal_groups] = app.multi_level_search(query);
            else
                %do singlelevel search
                [scores, signal_groups] = app.single_level_search(query);
            end
            
            toc
            
            %PLOT---------------------------------------------------------
            %from the retrieved scores, display the segments found
            %put scores in same dimension as SIGNAL
            new_scores = zeros(1, length(scores)+win_size);
            new_scores(fix(win_size/2):end-fix(win_size/2)-1) = scores;
            x_scores = linspace(1, length(new_scores), length(new_scores));
            half_win = fix(win_size/2);

            app.SCORE = new_scores;
            
            %3 - plot score function on axes
            plot(app.UIAxes_2, new_scores)

            %4 Order by k-most representative windows
            %5 highlight most important windows
            %6 plot eveything in the corresponding plots
            k = app.K_events_EditField.Value;
            arg_k = zeros(1, k);
            hold(app.UIAxes,'off');
            if(length(signal_groups)>1)
                for i = 1:size(app.SIGNAL, 1)
                    plot(app.UIAxes, app.norm_1_sig(app.SIGNAL(i, :))+(i-1)*1.5, 'LineWidth', 1, 'Color', '#808080')
                    hold(app.UIAxes, 'on');
                end
            else
                plot(app.UIAxes, app.norm_1_sig(app.SIGNAL), 'LineWidth', 0.8, 'Color', '#808080')
                hold(app.UIAxes, 'on');
            end

            cmap = colormap(jet(100));
            all_ks = [];
            all_scores = [];
            for k_i = 1:k
                %find max
                [max_ki, argmax_ki] = max(new_scores);
                %save index to plot around it
                arg_k(k_i) = fix(win_size/2) + argmax_ki;
                
                %remove the score and its surrounding 
                a = argmax_ki-half_win+1;
                b = argmax_ki+half_win;
                all_ks = [all_ks, argmax_ki];
                all_scores = [all_scores, max_ki];
                new_scores(a:b) = 0;
                %highlight on plot
                if(length(signal_groups)>1)
                    for i = 1:size(app.SIGNAL, 1)
                        %plot subsequences on signal
                        s_ = app.norm_1_sig(app.SIGNAL(i,:));
                        plot(app.UIAxes, x_scores(a:b), s_(a:b)+(i-1)*1.5, 'Color', cmap(fix(max_ki*85), :), 'LineWidth', 2)
                        %plot ordered on side
                        x_i = linspace(-1+i, i-0.5, length(a:b));
                        plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i)*max_ki, 'LineWidth', 2, 'Color', cmap(fix(max_ki*85), :))
                        hold(app.UIAxes_OrderedOutput, 'on')
                    end
                    
                else
                    s_ = app.norm_1_sig(app.SIGNAL);
                    plot(app.UIAxes, x_scores(a:b), s_(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
                    x_i = linspace(0, 0.5, length(a:b));
                    plot(app.UIAxes_OrderedOutput, x_i, s_(a:b)+(k-k_i), 'LineWidth', 2)
                    hold(app.UIAxes_OrderedOutput, 'on')
                end
                %keep searching the next higher window
            end
           
            app.OrderedPosition = all_ks;
            app.OrderedScore = all_scores;

            xlim(app.UIAxes_OrderedOutput, [-0.5, size(app.SIGNAL, 1)])
            hold(app.UIAxes_OrderedOutput, 'off')
            
            if(length(signal_groups)>1)
                app.recreatePatchwindow_multi(app.SignalSlider.Value);
            else
                app.recreatePatchwindow(app.SignalSlider.Value);
            end

        end

        % Value changed function: Channel_EditField
        function Channel_EditFieldValueChanged(app, event)
            value = app.Channel_EditField.Value;
            
            app.SIGNAL = app.norm_1_sig(app.XALL{value});

            %load keyword representation of the signal extracted in
            %multiple dimensions
            app.loadKeywords()
            
            %update slider value based on size of the signal
            nbr_windows = length(app.SIGNAL);
            window_size = app.WinSize_EditField.Value;
            app.SignalSlider.Limits = [fix(window_size/2), nbr_windows-fix(window_size/2)];
            
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')

            %create rectangle Patch on plot
            rectWidth = window_size;
            rectX = rectWidth*[0, 1];
            ylim(app.UIAxes, [-0.5, 1.5])
            rectY = ylim(app.UIAxes);
            app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
            xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])

            %make first update on edit field scores and subplot
            %update edit field scores
            app.updateEditFieldsScores(fix(window_size/2));

            %update plot
            app.updateSubSectionPlot(fix(window_size/2));
        end

        % Value changed function: WinSize_EditField
        function WinSize_EditFieldValueChanged(app, event)
%             if(app.ITER > 0)
%                 window_size = app.WinSize_EditField.Value;
%                 %load keyword representation of the signal extracted in
%                 %multiple dimensions
%                 app.loadKeywords()
%         
%                 %In this case, there are no classes...
%                 colors = ["#ff4500", "#ffde92", "#b8e4d5", "#f8daf8", "#4682b4", "#dda0dd"];
%                 app.COLORS = colors;                      
%                 
%                 hold(app.UIAxes, 'off');
%                 plot(app.UIAxes, app.SIGNAL, 'LineWidth', 1, 'Color', 'b')
%         
%                 %create rectangle Patch on plot
%                 rectWidth = window_size;
%                 rectX = rectWidth*[0, 1];
%                 ylim(app.UIAxes, [-0.5, 1.5])
%                 rectY = ylim(app.UIAxes);
%                 app.PATCH = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'r', "EdgeColor", "none", "FaceAlpha", 0.3);
%                 xlim(app.UIAxes, [-rectWidth, length(app.SIGNAL)+rectWidth])
%         
%                 %make first update on edit field scores and subplot
%                 %update edit field scores
%                 app.updateEditFieldsScores(fix(window_size/2));
%         
%                 %update plot
%                 app.updateSubSectionPlot(fix(window_size/2));
%             end
        end

        % Value changed function: K_events_EditField
        function K_events_EditFieldValueChanged(app, event)
%             k = app.K_events_EditField.Value;
%             win_size = app.WinSize_EditField.Value;
%             half_win = fix(win_size/2);
% 
%             new_scores = app.SCORE;
%             x_scores = linspace(1, length(app.SIGNAL), length(app.SIGNAL));
% 
%             arg_k = zeros(1, k);
%             hold(app.UIAxes,'off');
%             plot(app.UIAxes, app.SIGNAL, 'LineWidth', 0.8, 'Color', '#808080')
%             hold(app.UIAxes, 'on');
%             for k_i = 1:k
%                 %find max
%                 [max_ki, argmax_ki] = max(new_scores);
%                 %save index to plot around it
%                 arg_k(k_i) = half_win + argmax_ki;
%                 %remove the score and its surrounding 
%                 a = argmax_ki-half_win+1;
%                 b = argmax_ki+half_win;
%                 new_scores(a:b) = 0;
%                 %highlight on plot
%                 plot(app.UIAxes, x_scores(a:b), app.SIGNAL(a:b), 'Color', app.COLORS(5), 'LineWidth', 2)
%                 %keep searching the next higher window
%             end
%             
%             app.recreatePatchwindow(app.SignalSlider.Value);
            
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            file_data = [app.OrderedPosition; app.OrderedScore];
            name = app.SaveFile.Value;
            writematrix(file_data, name+".txt");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_OrderedOutput = uiaxes(app.UIFigure);
            app.UIAxes_OrderedOutput.XTick = [];
            app.UIAxes_OrderedOutput.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignal, true);
            app.LoadButton.Text = 'Load';
            app.SignalSlider = uislider(app.UIFigure);
            app.SignalSlider.Limits = [1 100];
            app.SignalSlider.MajorTickLabels = {''};
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @SignalSliderValueChanging, true);
            app.SignalSlider.MinorTicks = [];
            app.SignalSlider.Value = 1;
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpEditFieldLabel = uilabel(app.UIFigure);
            app.UpEditFieldLabel.Text = 'Up';
            app.UpEditField = uieditfield(app.UIFigure, 'numeric');
            app.DownEditFieldLabel = uilabel(app.UIFigure);
            app.DownEditFieldLabel.Text = 'Down';
            app.DownEditField = uieditfield(app.UIFigure, 'numeric');
            app.SymmetryEditFieldLabel = uilabel(app.UIFigure);
            app.SymmetryEditFieldLabel.Text = 'Symmetry';
            app.SymmetryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComplexityEditFieldLabel = uilabel(app.UIFigure);
            app.ComplexityEditFieldLabel.Text = 'Complexity';
            app.ComplexityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlatEditFieldLabel = uilabel(app.UIFigure);
            app.FlatEditFieldLabel.Text = 'Flat';
            app.FlatEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakEditFieldLabel = uilabel(app.UIFigure);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValleyEditFieldLabel = uilabel(app.UIFigure);
            app.ValleyEditFieldLabel.Text = 'Valley';
            app.ValleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.load_EditField = uieditfield(app.UIFigure, 'text');
            app.load_EditField.Placeholder = 'dataset name';
            app.load_EditField.Value = 'drive_AvsN_1';
            app.WinSize_EditField = uieditfield(app.UIFigure, 'numeric');
            app.WinSize_EditField.ValueChangedFcn = createCallbackFcn(app, @WinSize_EditFieldValueChanged, true);
            app.WinSize_EditField.Value = 50;
            app.Channel_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Channel_EditField.ValueChangedFcn = createCallbackFcn(app, @Channel_EditFieldValueChanged, true);
            app.Channel_EditField.Enable = 'off';
            app.Channel_EditField.Value = 1;
            app.K_events_EditField = uieditfield(app.UIFigure, 'numeric');
            app.K_events_EditField.ValueChangedFcn = createCallbackFcn(app, @K_events_EditFieldValueChanged, true);
            app.K_events_EditField.Value = 10;
            app.Query_EditField = uitextarea(app.UIFigure);
            app.Query_EditField.Placeholder = 'Enter query...';
            app.SaveFile = uieditfield(app.UIFigure, 'text');
            app.KeepButton = uibutton(app.UIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_update4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end